classdef Lab2_zad1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        KolorowankaButton       matlab.ui.control.Button
        Label_R                 matlab.ui.control.Label
        Label_G                 matlab.ui.control.Label
        Label_B                 matlab.ui.control.Label
        ZwierzetaDropDownLabel  matlab.ui.control.Label
        ZwierzetaDropDown       matlab.ui.control.DropDown
        WybranezwierzeLabel     matlab.ui.control.Label
        MEditFieldLabel         matlab.ui.control.Label
        MEditField              matlab.ui.control.NumericEditField
        NEditFieldLabel         matlab.ui.control.Label
        NEditField              matlab.ui.control.NumericEditField
        GenerujButton           matlab.ui.control.Button
        MinimumLabel            matlab.ui.control.Label
        MinimumEditField        matlab.ui.control.NumericEditField
        KrokEditFieldLabel      matlab.ui.control.Label
        KrokEditField           matlab.ui.control.NumericEditField
        MaksimumEditFieldLabel  matlab.ui.control.Label
        MaksimumEditField       matlab.ui.control.NumericEditField
        ObliczButton            matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: KolorowankaButton
        function KolorowankaButtonPushed(app, event)
            r = rand(1);
            g = rand(1);
            b = rand(1);
            set(app.UIFigure,'Color',[r,g,b]);
            set(app.Label_R,'Text', ['R = ', num2str(r)]);
            set(app.Label_G,'Text', ['G = ', num2str(g)]);
            set(app.Label_B,'Text', ['B = ', num2str(b)]);
        end

        % Value changed function: ZwierzetaDropDown
        function ZwierzetaDropDownValueChanged(app, event)
            value = app.ZwierzetaDropDown.Value;
            set(app.WybranezwierzeLabel, 'Text', ["Wybrane zwierze: ", value])
        end

        % Button pushed function: GenerujButton
        function GenerujButtonPushed(app, event)
            m = get(app.MEditField, 'Value');
            n = get(app.NEditField, 'Value');
            mac = rand(m,n);
            surf(app.UIAxes, mac);
            mesh(app.UIAxes2, mac);
            
        end

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            minn = get(app.MinimumEditField, 'Value');
            krok = get(app.KrokEditField, 'Value');
            maxx = get(app.MaksimumEditField, 'Value');
            t = minn:krok:maxx;
            y = sin(t) + cos(2*t);
            plot(app.UIAxes3, y);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KolorowankaButton = uibutton(app.UIFigure, 'push');
            app.KolorowankaButton.ButtonPushedFcn = createCallbackFcn(app, @KolorowankaButtonPushed, true);
            app.KolorowankaButton.Text = 'Kolorowanka';
            app.Label_R = uilabel(app.UIFigure);
            app.Label_R.Text = 'Label_R';
            app.Label_G = uilabel(app.UIFigure);
            app.Label_G.Text = 'Label_G';
            app.Label_B = uilabel(app.UIFigure);
            app.Label_B.Text = 'Label_B';
            app.ZwierzetaDropDownLabel = uilabel(app.UIFigure);
            app.ZwierzetaDropDownLabel.Text = 'Zwierzeta:';
            app.ZwierzetaDropDown = uidropdown(app.UIFigure);
            app.ZwierzetaDropDown.Items = {'pies', 'kot', 'mysz', 'koń', 'słoń', 'tygrys', 'zając', 'lew'};
            app.ZwierzetaDropDown.ValueChangedFcn = createCallbackFcn(app, @ZwierzetaDropDownValueChanged, true);
            app.ZwierzetaDropDown.Value = 'pies';
            app.WybranezwierzeLabel = uilabel(app.UIFigure);
            app.WybranezwierzeLabel.Text = 'Wybrane zwierze:';
            app.MEditFieldLabel = uilabel(app.UIFigure);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.UIFigure, 'numeric');
            app.MEditField.Value = 2;
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.NEditField.Value = 2;
            app.GenerujButton = uibutton(app.UIFigure, 'push');
            app.GenerujButton.ButtonPushedFcn = createCallbackFcn(app, @GenerujButtonPushed, true);
            app.GenerujButton.Text = 'Generuj';
            app.MinimumLabel = uilabel(app.UIFigure);
            app.MinimumLabel.Text = 'Minimum: ';
            app.MinimumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumEditField.Value = -10;
            app.KrokEditFieldLabel = uilabel(app.UIFigure);
            app.KrokEditFieldLabel.Text = 'Krok: ';
            app.KrokEditField = uieditfield(app.UIFigure, 'numeric');
            app.KrokEditField.Value = 0.01;
            app.MaksimumEditFieldLabel = uilabel(app.UIFigure);
            app.MaksimumEditFieldLabel.Text = 'Maksimum: ';
            app.MaksimumEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaksimumEditField.Value = 10;
            app.ObliczButton = uibutton(app.UIFigure, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab2_zad1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end