classdef zad1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab03_1UIFigure        matlab.ui.Figure
        PlikMenu               matlab.ui.container.Menu
        WczytajobrazMenu       matlab.ui.container.Menu
        ZapiszobrazMenu        matlab.ui.container.Menu
        KoniecMenu             matlab.ui.container.Menu
        EdycjaMenu             matlab.ui.container.Menu
        RGBdoGreyMenu          matlab.ui.container.Menu
        GreydoRGBMenu          matlab.ui.container.Menu
        SzerokoscLabel         matlab.ui.control.Label
        WysokoscLabel          matlab.ui.control.Label
        liczbaskladowychLabel  matlab.ui.control.Label
        PlusButton             matlab.ui.control.Button
        MinusButton            matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: KoniecMenu
        function KoniecMenuSelected(app, event)
            close(app.Lab03_1UIFigure);
        end

        % Menu selected function: WczytajobrazMenu
        function WczytajobrazMenuSelected(app, event)
            global obraz obraz_kopia;
            [plik, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz = imread([sciezka,plik]);
            obraz_kopia = obraz;
            imshow(obraz, 'Parent', app.UIAxes)
            [x,y,z] = size(obraz);
            set(app.SzerokoscLabel, 'Text', ['Szerokość: ', num2str(y)]);
            set(app.WysokoscLabel, 'Text', ['Wysokość: ', num2str(x)]);
            set(app.liczbaskladowychLabel, 'Text', ['Liczba Skladowych: ', num2str(z)]);
            set(app.UIAxes,'Position', [30,300,y,x]);
        end

        % Menu selected function: RGBdoGreyMenu
        function RGBdoGreyMenuSelected(app, event)
            global obraz;
            obraz = rgb2gray(obraz);
            imshow(obraz, 'Parent', app.UIAxes2)
        end

        % Menu selected function: GreydoRGBMenu
        function GreydoRGBMenuSelected(app, event)
            global obraz obraz_kopia;
            obraz = obraz_kopia;
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ZapiszobrazMenu
        function ZapiszobrazMenuSelected(app, event)
            global obraz;
            [plik, sciezka] = uiputfile('*.jpg;*.png;*.bmp','Zapisz obraz');
            imwrite(obraz, [sciezka, plik]);
            
        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            global obraz;
            obraz = imresize(obraz, 1.1);
            imshow(obraz, 'Parent', app.UIAxes)
            [x,y,z] = size(obraz);
            set(app.SzerokoscLabel, 'Text', ['Szerokość: ', num2str(y)]);
            set(app.WysokoscLabel, 'Text', ['Wysokość: ', num2str(x)]);
            set(app.liczbaskladowychLabel, 'Text', ['Liczba Skladowych: ', num2str(z)]);
            set(app.UIAxes,'Position', [30,300,y,x]);
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            global obraz;
            obraz = imresize(obraz, 0.9);
            imshow(obraz, 'Parent', app.UIAxes)
            [x,y,z] = size(obraz);
            set(app.SzerokoscLabel, 'Text', ['Szerokość: ', num2str(y)]);
            set(app.WysokoscLabel, 'Text', ['Wysokość: ', num2str(x)]);
            set(app.liczbaskladowychLabel, 'Text', ['Liczba Skladowych: ', num2str(z)]);
            set(app.UIAxes,'Position', [30,300,y,x]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab03_1UIFigure = uifigure('Visible', 'off');
            app.Lab03_1UIFigure.Name = 'Lab03_1';
            app.PlikMenu = uimenu(app.Lab03_1UIFigure);
            app.PlikMenu.Text = 'Plik';
            app.WczytajobrazMenu = uimenu(app.PlikMenu);
            app.WczytajobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajobrazMenuSelected, true);
            app.WczytajobrazMenu.Text = 'Wczytaj obraz';
            app.ZapiszobrazMenu = uimenu(app.PlikMenu);
            app.ZapiszobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszobrazMenuSelected, true);
            app.ZapiszobrazMenu.Text = 'Zapisz obraz';
            app.KoniecMenu = uimenu(app.PlikMenu);
            app.KoniecMenu.MenuSelectedFcn = createCallbackFcn(app, @KoniecMenuSelected, true);
            app.KoniecMenu.Text = 'Koniec';
            app.EdycjaMenu = uimenu(app.Lab03_1UIFigure);
            app.EdycjaMenu.Text = 'Edycja';
            app.RGBdoGreyMenu = uimenu(app.EdycjaMenu);
            app.RGBdoGreyMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBdoGreyMenuSelected, true);
            app.RGBdoGreyMenu.Text = 'RGB do Grey';
            app.GreydoRGBMenu = uimenu(app.EdycjaMenu);
            app.GreydoRGBMenu.MenuSelectedFcn = createCallbackFcn(app, @GreydoRGBMenuSelected, true);
            app.GreydoRGBMenu.Text = 'Grey do RGB';
            app.SzerokoscLabel = uilabel(app.Lab03_1UIFigure);
            app.SzerokoscLabel.Text = 'Szerokosc';
            app.WysokoscLabel = uilabel(app.Lab03_1UIFigure);
            app.WysokoscLabel.Text = 'Wysokosc';
            app.liczbaskladowychLabel = uilabel(app.Lab03_1UIFigure);
            app.liczbaskladowychLabel.Text = 'liczba skladowych';
            app.PlusButton = uibutton(app.Lab03_1UIFigure, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = 'Plus';
            app.MinusButton = uibutton(app.Lab03_1UIFigure, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Text = 'Minus';
            app.UIAxes = uiaxes(app.Lab03_1UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.Lab03_1UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.Lab03_1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zad1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab03_1UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab03_1UIFigure)
        end
    end
end