classdef zad1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab03_1UIFigure        matlab.ui.Figure
        PlikMenu               matlab.ui.container.Menu
        WczytajobrazMenu       matlab.ui.container.Menu
        ZapiszobrazMenu        matlab.ui.container.Menu
        KoniecMenu             matlab.ui.container.Menu
        EdycjaMenu             matlab.ui.container.Menu
        RGBdoGreyMenu          matlab.ui.container.Menu
        GreydoRGBMenu          matlab.ui.container.Menu
        RGB1Menu               matlab.ui.container.Menu
        RGB2Menu               matlab.ui.container.Menu
        SzerokoscLabel         matlab.ui.control.Label
        WysokoscLabel          matlab.ui.control.Label
        liczbaskladowychLabel  matlab.ui.control.Label
        PlusButton             matlab.ui.control.Button
        MinusButton            matlab.ui.control.Button
        SliderLabel            matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        RSliderLabel           matlab.ui.control.Label
        RSlider                matlab.ui.control.Slider
        GSliderLabel           matlab.ui.control.Label
        GSlider                matlab.ui.control.Slider
        BSliderLabel           matlab.ui.control.Label
        BSlider                matlab.ui.control.Slider
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes4                matlab.ui.control.UIAxes
        UIAxes5                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: KoniecMenu
        function KoniecMenuSelected(app, event)
            close(app.Lab03_1UIFigure);
        end

        % Menu selected function: WczytajobrazMenu
        function WczytajobrazMenuSelected(app, event)
            global obraz obraz_kopia;
            [plik, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz = imread([sciezka,plik]);
            obraz_kopia = obraz;
            imshow(obraz, 'Parent', app.UIAxes)
            [x,y,z] = size(obraz);
            set(app.SzerokoscLabel, 'Text', ['Szerokość: ', num2str(y)]);
            set(app.WysokoscLabel, 'Text', ['Wysokość: ', num2str(x)]);
            set(app.liczbaskladowychLabel, 'Text', ['Liczba Skladowych: ', num2str(z)]);
            %set(app.UIAxes,'Position', [100,400,y,x]);
        end

        % Menu selected function: RGBdoGreyMenu
        function RGBdoGreyMenuSelected(app, event)
            global obraz;
            obraz_new = rgb2gray(obraz);
            imshow(obraz_new, 'Parent', app.UIAxes2)
        end

        % Menu selected function: GreydoRGBMenu
        function GreydoRGBMenuSelected(app, event)
            global obraz obraz_kopia;
            obraz = obraz_kopia;
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ZapiszobrazMenu
        function ZapiszobrazMenuSelected(app, event)
            global obraz;
            [plik, sciezka] = uiputfile('*.jpg;*.png;*.bmp','Zapisz obraz');
            imwrite(obraz, [sciezka, plik]);
            
        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            global obraz;
            obraz = imresize(obraz, 1.1);
            imshow(obraz, 'Parent', app.UIAxes)
            [x,y,z] = size(obraz);
            set(app.SzerokoscLabel, 'Text', ['Szerokość: ', num2str(y)]);
            set(app.WysokoscLabel, 'Text', ['Wysokość: ', num2str(x)]);
            set(app.liczbaskladowychLabel, 'Text', ['Liczba Skladowych: ', num2str(z)]);
            set(app.UIAxes,'Position', [100,400,y,x]);
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            global obraz;
            obraz = imresize(obraz, 0.9);
            imshow(obraz, 'Parent', app.UIAxes)
            [x,y,z] = size(obraz);
            set(app.SzerokoscLabel, 'Text', ['Szerokość: ', num2str(y)]);
            set(app.WysokoscLabel, 'Text', ['Wysokość: ', num2str(x)]);
            set(app.liczbaskladowychLabel, 'Text', ['Liczba Skladowych: ', num2str(z)]);
            set(app.UIAxes,'Position', [100,400,y,x]);
        end

        % Menu selected function: RGB1Menu
        function RGB1MenuSelected(app, event)
            global obraz
            imshow(obraz(:,:,1), 'Parent',app.UIAxes3);
            imshow(obraz(:,:,2), 'Parent',app.UIAxes4);
            imshow(obraz(:,:,3), 'Parent',app.UIAxes5);
        end

        % Menu selected function: RGB2Menu
        function RGB2MenuSelected(app, event)
            global obraz
            obraz_R = obraz;
            obraz_R(:,:,2) = 0;
            obraz_R(:,:,3) = 0;
            imshow(obraz_R, 'Parent',app.UIAxes3);
            obraz_G = obraz;
            obraz_G(:,:,1) = 0;
            obraz_G(:,:,3) = 0;
            imshow(obraz_G, 'Parent',app.UIAxes4);
            obraz_B = obraz;
            obraz_B(:,:,1) = 0;
            obraz_B(:,:,2) = 0;
            imshow(obraz_B, 'Parent',app.UIAxes5);
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            global obraz_kopia
            obraz_new = obraz_kopia;
            
            obraz_new = obraz_new * changingValue;
            imshow(obraz_new, 'Parent',app.UIAxes);
            
            obraz_newGrey = rgb2gray(obraz_new);
            imshow(obraz_newGrey, 'Parent',app.UIAxes2);
        end

        % Value changing function: RSlider
        function RSliderValueChanging(app, event)
            changingValue = event.Value;
            global obraz
            img_new = obraz;
            
            img_new(:,:,1) = img_new(:,:,1) * changingValue;
            img_new(:,:,2) = img_new(:,:,2) * app.GSlider.Value;
            img_new(:,:,3) = img_new(:,:,3) * app.BSlider.Value;
            imshow(img_new, 'Parent',app.UIAxes);
            
            obraz_R = img_new;
            obraz_R(:,:,2) = 0;
            obraz_R(:,:,3) = 0;
            imshow(obraz_R, 'Parent',app.UIAxes3)
            
            img_grey = rgb2gray(img_new);
            imshow(img_grey, 'Parent', app.UIAxes2);
        end

        % Value changing function: GSlider
        function GSliderValueChanging(app, event)
            changingValue = event.Value;
            global obraz
            img_new = obraz;
            
            img_new(:,:,2) = img_new(:,:,2) * changingValue;
            img_new(:,:,1) = img_new(:,:,1) * app.RSlider.Value;
            img_new(:,:,3) = img_new(:,:,3) * app.BSlider.Value;
            imshow(img_new, 'Parent',app.UIAxes);
            
            obraz_G = img_new;
            obraz_G(:,:,1) = 0;
            obraz_G(:,:,3) = 0;
            imshow(obraz_G, 'Parent',app.UIAxes4);
            
            img_grey = rgb2gray(img_new);
            imshow(img_grey, 'Parent', app.UIAxes2);
            
            
        end

        % Value changing function: BSlider
        function BSliderValueChanging(app, event)
            changingValue = event.Value;
            global obraz
            img_new = obraz;
            
            img_new(:,:,3) = img_new(:,:,3) * changingValue;
            img_new(:,:,2) = img_new(:,:,2) * app.GSlider.Value;
            img_new(:,:,1) = img_new(:,:,1) * app.RSlider.Value;
            imshow(img_new, 'Parent',app.UIAxes);
            
            obraz_B = img_new;
            obraz_B(:,:,1) = 0;
            obraz_B(:,:,2) = 0;
            imshow(obraz_B, 'Parent',app.UIAxes5);
            
            img_grey = rgb2gray(img_new);
            imshow(img_grey, 'Parent', app.UIAxes2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab03_1UIFigure = uifigure('Visible', 'off');
            app.Lab03_1UIFigure.Name = 'Lab03_1';
            app.PlikMenu = uimenu(app.Lab03_1UIFigure);
            app.PlikMenu.Text = 'Plik';
            app.WczytajobrazMenu = uimenu(app.PlikMenu);
            app.WczytajobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajobrazMenuSelected, true);
            app.WczytajobrazMenu.Text = 'Wczytaj obraz';
            app.ZapiszobrazMenu = uimenu(app.PlikMenu);
            app.ZapiszobrazMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszobrazMenuSelected, true);
            app.ZapiszobrazMenu.Text = 'Zapisz obraz';
            app.KoniecMenu = uimenu(app.PlikMenu);
            app.KoniecMenu.MenuSelectedFcn = createCallbackFcn(app, @KoniecMenuSelected, true);
            app.KoniecMenu.Text = 'Koniec';
            app.EdycjaMenu = uimenu(app.Lab03_1UIFigure);
            app.EdycjaMenu.Text = 'Edycja';
            app.RGBdoGreyMenu = uimenu(app.EdycjaMenu);
            app.RGBdoGreyMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBdoGreyMenuSelected, true);
            app.RGBdoGreyMenu.Text = 'RGB do Grey';
            app.GreydoRGBMenu = uimenu(app.EdycjaMenu);
            app.GreydoRGBMenu.MenuSelectedFcn = createCallbackFcn(app, @GreydoRGBMenuSelected, true);
            app.GreydoRGBMenu.Text = 'Grey do RGB';
            app.RGB1Menu = uimenu(app.EdycjaMenu);
            app.RGB1Menu.MenuSelectedFcn = createCallbackFcn(app, @RGB1MenuSelected, true);
            app.RGB1Menu.Text = 'RGB 1';
            app.RGB2Menu = uimenu(app.EdycjaMenu);
            app.RGB2Menu.MenuSelectedFcn = createCallbackFcn(app, @RGB2MenuSelected, true);
            app.RGB2Menu.Text = 'RGB 2';
            app.SzerokoscLabel = uilabel(app.Lab03_1UIFigure);
            app.SzerokoscLabel.Text = 'Szerokosc';
            app.WysokoscLabel = uilabel(app.Lab03_1UIFigure);
            app.WysokoscLabel.Text = 'Wysokosc';
            app.liczbaskladowychLabel = uilabel(app.Lab03_1UIFigure);
            app.liczbaskladowychLabel.Text = 'liczba skladowych';
            app.PlusButton = uibutton(app.Lab03_1UIFigure, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = 'Plus';
            app.MinusButton = uibutton(app.Lab03_1UIFigure, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Text = 'Minus';
            app.SliderLabel = uilabel(app.Lab03_1UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Lab03_1UIFigure);
            app.Slider.Limits = [0 2];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 1;
            app.RSliderLabel = uilabel(app.Lab03_1UIFigure);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.Lab03_1UIFigure);
            app.RSlider.Limits = [0 2];
            app.RSlider.ValueChangingFcn = createCallbackFcn(app, @RSliderValueChanging, true);
            app.RSlider.Value = 1;
            app.GSliderLabel = uilabel(app.Lab03_1UIFigure);
            app.GSliderLabel.Text = 'G';
            app.GSlider = uislider(app.Lab03_1UIFigure);
            app.GSlider.Limits = [0 2];
            app.GSlider.ValueChangingFcn = createCallbackFcn(app, @GSliderValueChanging, true);
            app.GSlider.Value = 1;
            app.BSliderLabel = uilabel(app.Lab03_1UIFigure);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.Lab03_1UIFigure);
            app.BSlider.Limits = [0 2];
            app.BSlider.ValueChangingFcn = createCallbackFcn(app, @BSliderValueChanging, true);
            app.BSlider.Value = 1;
            app.UIAxes = uiaxes(app.Lab03_1UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.Lab03_1UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.Lab03_1UIFigure);
            title(app.UIAxes3, 'Skladowa R')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.Lab03_1UIFigure);
            title(app.UIAxes4, 'Skladowa G')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes5 = uiaxes(app.Lab03_1UIFigure);
            title(app.UIAxes5, 'Skladowa B')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            % Show the figure after all components are created
            app.Lab03_1UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = zad1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab03_1UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab03_1UIFigure)
        end
    end
end