classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PlikMenu                 matlab.ui.container.Menu
        WczytajMenu              matlab.ui.container.Menu
        ZapiszMenu               matlab.ui.container.Menu
        KoniecMenu               matlab.ui.container.Menu
        EdycjaMenu               matlab.ui.container.Menu
        averageMenu              matlab.ui.container.Menu
        discMenu                 matlab.ui.container.Menu
        laplacianMenu            matlab.ui.container.Menu
        logMenu                  matlab.ui.container.Menu
        motionMenu               matlab.ui.container.Menu
        PrewittMenu              matlab.ui.container.Menu
        SobelMenu                matlab.ui.container.Menu
        thresholdingMenu         matlab.ui.container.Menu
        thresholdingSliderLabel  matlab.ui.control.Label
        thresholdingSlider       matlab.ui.control.Slider
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: WczytajMenu
        function WczytajMenuSelected(app, event)
            global obraz obraz_new;
            [plik, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz = imread([sciezka,plik]);
            obraz_new = obraz;
            imshow(obraz, 'Parent', app.UIAxes)
            [x,y,z] = size(obraz);
            %set(app.SzerokoscLabel, 'Text', ['Szerokość: ', num2str(y)]);
            %set(app.WysokoscLabel, 'Text', ['Wysokość: ', num2str(x)]);
            %set(app.liczbaskladowychLabel, 'Text', ['Liczba Skladowych: ', num2str(z)]);
        end

        % Menu selected function: KoniecMenu
        function KoniecMenuSelected(app, event)
            close(app.Lab03_1UIFigure);
        end

        % Menu selected function: ZapiszMenu
        function ZapiszMenuSelected(app, event)
            global obraz_filtered;
            [plik, sciezka] = uiputfile('*.jpg;*.png;*.bmp','Zapisz obraz');
            imwrite(obraz_filtered, [sciezka, plik]);
        end

        % Menu selected function: averageMenu
        function averageMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('average', 3);
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: discMenu
        function discMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('disk', 5);
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
           
        end

        % Menu selected function: laplacianMenu
        function laplacianMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('laplacian', 0.5);
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: logMenu
        function logMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('log', 5, 0.5);
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: motionMenu
        function motionMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('motion', 10, 45);
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: PrewittMenu
        function PrewittMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('prewitt');
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('sobel');
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Value changing function: thresholdingSlider
        function thresholdingSliderValueChanging(app, event)
            global obraz obraz_filtered;
            changingValue = event.Value;
            [x,y,z] = size(obraz);
            
            for i = 1 : x
                for j = 1 : y
                    for k = 1 : z
                        if (obraz(i,j,k) > changingValue)
                            obraz_filtered(i,j,k) = 0;
                        else
                            obraz_filtered(i,j,k) = 255;
                        end
                    end
                end
            end
            
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: thresholdingMenu
        function thresholdingMenuSelected(app, event)
            global obraz obraz_filtered;
            pr = 120;
            [x,y,z] = size(obraz);
            
            for i = 1 : x
                for j = 1 : y
                    for k = 1 : z
                        if (obraz(i,j,k) > pr)
                            obraz_filtered(i,j,k) = 0;
                        else
                            obraz_filtered(i,j,k) = 255;
                        end
                    end
                end
            end
            
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlikMenu = uimenu(app.UIFigure);
            app.PlikMenu.Text = 'Plik';
            app.WczytajMenu = uimenu(app.PlikMenu);
            app.WczytajMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajMenuSelected, true);
            app.WczytajMenu.Text = 'Wczytaj';
            app.ZapiszMenu = uimenu(app.PlikMenu);
            app.ZapiszMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszMenuSelected, true);
            app.ZapiszMenu.Text = 'Zapisz';
            app.KoniecMenu = uimenu(app.PlikMenu);
            app.KoniecMenu.MenuSelectedFcn = createCallbackFcn(app, @KoniecMenuSelected, true);
            app.KoniecMenu.Text = 'Koniec';
            app.EdycjaMenu = uimenu(app.UIFigure);
            app.EdycjaMenu.Text = 'Edycja';
            app.averageMenu = uimenu(app.EdycjaMenu);
            app.averageMenu.MenuSelectedFcn = createCallbackFcn(app, @averageMenuSelected, true);
            app.averageMenu.Text = 'average';
            app.discMenu = uimenu(app.EdycjaMenu);
            app.discMenu.MenuSelectedFcn = createCallbackFcn(app, @discMenuSelected, true);
            app.discMenu.Text = 'disc';
            app.laplacianMenu = uimenu(app.EdycjaMenu);
            app.laplacianMenu.MenuSelectedFcn = createCallbackFcn(app, @laplacianMenuSelected, true);
            app.laplacianMenu.Text = 'laplacian';
            app.logMenu = uimenu(app.EdycjaMenu);
            app.logMenu.MenuSelectedFcn = createCallbackFcn(app, @logMenuSelected, true);
            app.logMenu.Text = 'log';
            app.motionMenu = uimenu(app.EdycjaMenu);
            app.motionMenu.MenuSelectedFcn = createCallbackFcn(app, @motionMenuSelected, true);
            app.motionMenu.Text = 'motion';
            app.PrewittMenu = uimenu(app.EdycjaMenu);
            app.PrewittMenu.MenuSelectedFcn = createCallbackFcn(app, @PrewittMenuSelected, true);
            app.PrewittMenu.Text = 'Prewitt';
            app.SobelMenu = uimenu(app.EdycjaMenu);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel';
            app.thresholdingMenu = uimenu(app.EdycjaMenu);
            app.thresholdingMenu.MenuSelectedFcn = createCallbackFcn(app, @thresholdingMenuSelected, true);
            app.thresholdingMenu.Text = 'thresholding';
            app.thresholdingSliderLabel = uilabel(app.UIFigure);
            app.thresholdingSliderLabel.Text = 'thresholding';
            app.thresholdingSlider = uislider(app.UIFigure);
            app.thresholdingSlider.Limits = [0 255];
            app.thresholdingSlider.ValueChangingFcn = createCallbackFcn(app, @thresholdingSliderValueChanging, true);
            app.thresholdingSlider.Value = 120;
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end