classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlikMenu                  matlab.ui.container.Menu
        WczytajMenu               matlab.ui.container.Menu
        ZapiszMenu                matlab.ui.container.Menu
        KoniecMenu                matlab.ui.container.Menu
        EdycjaMenu                matlab.ui.container.Menu
        averageMenu               matlab.ui.container.Menu
        discMenu                  matlab.ui.container.Menu
        laplacianMenu             matlab.ui.container.Menu
        logMenu                   matlab.ui.container.Menu
        motionMenu                matlab.ui.container.Menu
        PrewittMenu               matlab.ui.container.Menu
        SobelMenu                 matlab.ui.container.Menu
        thresholdingMenu          matlab.ui.container.Menu
        KrawedziowanieMenu        matlab.ui.container.Menu
        Poziomypodstawowy1Menu    matlab.ui.container.Menu
        Poziomywzmocniony1Menu    matlab.ui.container.Menu
        Poziomypodstawowy2Menu    matlab.ui.container.Menu
        Poziomywzmocniony2Menu    matlab.ui.container.Menu
        Pionowypodstawowy1Menu    matlab.ui.container.Menu
        Pionowywzmocniony1Menu    matlab.ui.container.Menu
        Pionowypodstawowy2Menu    matlab.ui.container.Menu
        Pionowypodstawowy2Menu_2  matlab.ui.container.Menu
        Skos1Menu                 matlab.ui.container.Menu
        Skos2Menu                 matlab.ui.container.Menu
        Skos3Menu                 matlab.ui.container.Menu
        Skos4Menu                 matlab.ui.container.Menu
        Skoswzmocniony1Menu       matlab.ui.container.Menu
        Skoswzmocniony2Menu       matlab.ui.container.Menu
        Skoswzmocniony3Menu       matlab.ui.container.Menu
        Skoswzmocniony4Menu       matlab.ui.container.Menu
        KierunkowewszystkieMenu   matlab.ui.container.Menu
        DetektorcechMenu          matlab.ui.container.Menu
        CechyHarrisMenu           matlab.ui.container.Menu
        CechyFastMenu             matlab.ui.container.Menu
        CechyKazeMenu             matlab.ui.container.Menu
        MSER1Menu                 matlab.ui.container.Menu
        MSER2Menu                 matlab.ui.container.Menu
        thresholdingSlider        matlab.ui.control.Slider
        thresholdingSliderLabel   matlab.ui.control.Label
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: WczytajMenu
        function WczytajMenuSelected(app, event)
            global obraz obraz_new;
            [plik, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz = imread([sciezka,plik]);
            obraz_new = obraz;
            imshow(obraz, 'Parent', app.UIAxes)
            [x,y,z] = size(obraz);
            %set(app.SzerokoscLabel, 'Text', ['Szerokość: ', num2str(y)]);
            %set(app.WysokoscLabel, 'Text', ['Wysokość: ', num2str(x)]);
            %set(app.liczbaskladowychLabel, 'Text', ['Liczba Skladowych: ', num2str(z)]);
        end

        % Menu selected function: KoniecMenu
        function KoniecMenuSelected(app, event)
            close(app.Lab03_1UIFigure);
        end

        % Menu selected function: ZapiszMenu
        function ZapiszMenuSelected(app, event)
            global obraz_filtered;
            [plik, sciezka] = uiputfile('*.jpg;*.png;*.bmp','Zapisz obraz');
            imwrite(obraz_filtered, [sciezka, plik]);
        end

        % Menu selected function: averageMenu
        function averageMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('average', 3);
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: discMenu
        function discMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('disk', 5);
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
           
        end

        % Menu selected function: laplacianMenu
        function laplacianMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('laplacian', 0.5);
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: logMenu
        function logMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('log', 5, 0.5);
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: motionMenu
        function motionMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('motion', 10, 45);
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: PrewittMenu
        function PrewittMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('prewitt');
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
            global obraz obraz_filtered;
            filtra = fspecial('sobel');
            obraz_filtered = imfilter(obraz, filtra);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Value changing function: thresholdingSlider
        function thresholdingSliderValueChanging(app, event)
            global obraz obraz_filtered;
            changingValue = event.Value;
            [x,y,z] = size(obraz);
            
            for i = 1 : x
                for j = 1 : y
                    for k = 1 : z
                        if (obraz(i,j,k) > changingValue)
                            obraz_filtered(i,j,k) = 0;
                        else
                            obraz_filtered(i,j,k) = 255;
                        end
                    end
                end
            end
            
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: thresholdingMenu
        function thresholdingMenuSelected(app, event)
            global obraz obraz_filtered;
            pr = 120;
            [x,y,z] = size(obraz);
            
            for i = 1 : x
                for j = 1 : y
                    for k = 1 : z
                        if (obraz(i,j,k) > pr)
                            obraz_filtered(i,j,k) = 0;
                        else
                            obraz_filtered(i,j,k) = 255;
                        end
                    end
                end
            end
            
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Poziomypodstawowy1Menu
        function Poziomypodstawowy1MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [1, 1, 1; 0, 0, 0; -1, -1, -1];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Poziomywzmocniony1Menu
        function Poziomywzmocniony1MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [2, 2, 2; 0, 0, 0; -2, -2, -2];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Poziomypodstawowy2Menu
        function Poziomypodstawowy2MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [-1, -1, -1; 0, 0, 0; 1, 1, 1];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Poziomywzmocniony2Menu
        function Poziomywzmocniony2MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [-2, -2, -2; 0, 0, 0; 2, 2, 2];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Pionowypodstawowy1Menu
        function Pionowypodstawowy1MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [1, 0, -1; 1, 0, -1; 1, 0, -1];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Pionowywzmocniony1Menu
        function Pionowywzmocniony1MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [2, 0, -2; 2, 0, -2; 2, 0, -2];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Pionowypodstawowy2Menu
        function Pionowypodstawowy2MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [-1, 0, 1; -1, 0, 1; -1, 0, 1];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Pionowypodstawowy2Menu_2
        function Pionowypodstawowy2Menu_2Selected(app, event)
            global obraz obraz_filtered;
            maska = [-2, 0, 2; -2, 0, 2; -2, 0, 2];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Skos1Menu
        function Skos1MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [0, 1, 1; -1, 0, 1; -1, -1, 0];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Skoswzmocniony1Menu
        function Skoswzmocniony1MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [0, 2, 2; -2, 0, 2; -2, -2, 0];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Skos2Menu
        function Skos2MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [0, -1, -1; 1, 0, -1; 1, 1, 0];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: KrawedziowanieMenu
        function KrawedziowanieMenuSelected(app, event)
            
        end

        % Menu selected function: Skoswzmocniony2Menu
        function Skoswzmocniony2MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [0, -2, -2; 2, 0, -2; 2, 2, 0];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Skos3Menu
        function Skos3MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [1, 1, 0; 1, 0, -1; 0, -1, -1];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Skos4Menu
        function Skos4MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [-1, -1, 0; -1, 0, 1; 0, 1, 1];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Skoswzmocniony3Menu
        function Skoswzmocniony3MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [2, 2, 0; 2, 0, -2; 0, -2, -2];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: Skoswzmocniony4Menu
        function Skoswzmocniony4MenuSelected(app, event)
            global obraz obraz_filtered;
            maska = [-2, -2, 0; -2, 0, 2; 0, 2, 2];
            obraz_filtered = imfilter(obraz, maska);
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: KierunkowewszystkieMenu
        function KierunkowewszystkieMenuSelected(app, event)
            global obraz obraz_filtered;
            maska1 = [1, 1, 1; 0, 0, 0; -1, -1, -1];
            maska2 = [-1, -1, -1; 0, 0, 0; 1, 1, 1];
            maska3 = [0, 1, 1; -1, 0, 1; -1, -1, 0];
            maska4 = [0, -1, -1; 1, 0, -1; 1, 1, 0];
            
            obraz_filtered1 = imfilter(obraz, maska1);
            obraz_filtered2 = imfilter(obraz, maska2);
            obraz_filtered3 = imfilter(obraz, maska3);
            obraz_filtered4 = imfilter(obraz, maska4);
            obraz_filtered = obraz_filtered1 + obraz_filtered2 + obraz_filtered3 + obraz_filtered4;
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
        end

        % Menu selected function: CechyHarrisMenu
        function CechyHarrisMenuSelected(app, event)
            global obraz obraz_filtered;
            obraz_filtered = obraz;
            obraz_gray = rgb2gray(obraz);
            corners = detectHarrisFeatures(obraz_gray);
            disp(corners);
            loc = corners.Location;
            [a, b] = size(loc);
            for i = 2 : a - 1
                obraz_filtered(floor(loc(i, 2)), floor(loc(i, 1)),2) = 255;
                obraz_filtered(floor(loc(i, 2)+1), floor(loc(i, 1)),3) = 255;
                obraz_filtered(floor(loc(i, 2)-1), floor(loc(i, 1)),3) = 255;
                obraz_filtered(floor(loc(i, 2)), floor(loc(i, 1)+1),3) = 255;
                obraz_filtered(floor(loc(i, 2)), floor(loc(i, 1)-1),3) = 255;
            end
            
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
            
            figure;
            imshow(obraz);
            hold on;
            plot(corners.selectStrongest(80));
        end

        % Menu selected function: CechyFastMenu
        function CechyFastMenuSelected(app, event)
            global obraz obraz_filtered;
            obraz_filtered = obraz;
            obraz_gray = rgb2gray(obraz);
            corners = detectFASTFeatures(obraz_gray);
            disp(corners);
            loc = corners.Location;
            [a, b] = size(loc);
            for i = 2 : a - 1
                obraz_filtered(floor(loc(i, 2)), floor(loc(i, 1)),2) = 255;
                obraz_filtered(floor(loc(i, 2)+1), floor(loc(i, 1)),3) = 255;
                obraz_filtered(floor(loc(i, 2)-1), floor(loc(i, 1)),3) = 255;
                obraz_filtered(floor(loc(i, 2)), floor(loc(i, 1)+1),3) = 255;
                obraz_filtered(floor(loc(i, 2)), floor(loc(i, 1)-1),3) = 255;
            end
            
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
            
            figure;
            imshow(obraz);
            hold on;
            plot(corners.selectStrongest(50));
        end

        % Menu selected function: CechyKazeMenu
        function CechyKazeMenuSelected(app, event)
            global obraz obraz_filtered;
            obraz_filtered = obraz;
            obraz_gray = rgb2gray(obraz);
            corners = detectKAZEFeatures(obraz_gray);
            disp(corners);
            loc = corners.Location;
            [a, b] = size(loc);
            for i = 2 : a - 1
                obraz_filtered(floor(loc(i, 2)), floor(loc(i, 1)),2) = 255;
                obraz_filtered(floor(loc(i, 2)+1), floor(loc(i, 1)),3) = 255;
                obraz_filtered(floor(loc(i, 2)-1), floor(loc(i, 1)),3) = 255;
                obraz_filtered(floor(loc(i, 2)), floor(loc(i, 1)+1),3) = 255;
                obraz_filtered(floor(loc(i, 2)), floor(loc(i, 1)-1),3) = 255;
            end
            
            imshow(obraz_filtered, 'Parent', app.UIAxes_2);
            
            figure;
            imshow(obraz);
            hold on;
            plot(selectStrongest(corners,50));
        end

        % Menu selected function: MSER1Menu
        function MSER1MenuSelected(app, event)
            global obraz obraz_filtered;
            obraz_filtered = obraz;
            obraz_gray = rgb2gray(obraz);
            regions = detectMSERFeatures(obraz_gray);
            
            
            figure; imshow(obraz); hold on;
            plot(regions,'showPixelList',true,'showEllipses',false);
        end

        % Menu selected function: MSER2Menu
        function MSER2MenuSelected(app, event)
            global obraz obraz_filtered;
            obraz_filtered = obraz;
            obraz_gray = rgb2gray(obraz);
            regions = detectMSERFeatures(obraz_gray);
            
            figure; imshow(obraz); hold on;
            plot(regions);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlikMenu = uimenu(app.UIFigure);
            app.PlikMenu.Text = 'Plik';
            app.WczytajMenu = uimenu(app.PlikMenu);
            app.WczytajMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajMenuSelected, true);
            app.WczytajMenu.Text = 'Wczytaj';
            app.ZapiszMenu = uimenu(app.PlikMenu);
            app.ZapiszMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszMenuSelected, true);
            app.ZapiszMenu.Text = 'Zapisz';
            app.KoniecMenu = uimenu(app.PlikMenu);
            app.KoniecMenu.MenuSelectedFcn = createCallbackFcn(app, @KoniecMenuSelected, true);
            app.KoniecMenu.Text = 'Koniec';
            app.EdycjaMenu = uimenu(app.UIFigure);
            app.EdycjaMenu.Text = 'Edycja';
            app.averageMenu = uimenu(app.EdycjaMenu);
            app.averageMenu.MenuSelectedFcn = createCallbackFcn(app, @averageMenuSelected, true);
            app.averageMenu.Text = 'average';
            app.discMenu = uimenu(app.EdycjaMenu);
            app.discMenu.MenuSelectedFcn = createCallbackFcn(app, @discMenuSelected, true);
            app.discMenu.Text = 'disc';
            app.laplacianMenu = uimenu(app.EdycjaMenu);
            app.laplacianMenu.MenuSelectedFcn = createCallbackFcn(app, @laplacianMenuSelected, true);
            app.laplacianMenu.Text = 'laplacian';
            app.logMenu = uimenu(app.EdycjaMenu);
            app.logMenu.MenuSelectedFcn = createCallbackFcn(app, @logMenuSelected, true);
            app.logMenu.Text = 'log';
            app.motionMenu = uimenu(app.EdycjaMenu);
            app.motionMenu.MenuSelectedFcn = createCallbackFcn(app, @motionMenuSelected, true);
            app.motionMenu.Text = 'motion';
            app.PrewittMenu = uimenu(app.EdycjaMenu);
            app.PrewittMenu.MenuSelectedFcn = createCallbackFcn(app, @PrewittMenuSelected, true);
            app.PrewittMenu.Text = 'Prewitt';
            app.SobelMenu = uimenu(app.EdycjaMenu);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel';
            app.thresholdingMenu = uimenu(app.EdycjaMenu);
            app.thresholdingMenu.MenuSelectedFcn = createCallbackFcn(app, @thresholdingMenuSelected, true);
            app.thresholdingMenu.Text = 'thresholding';
            app.KrawedziowanieMenu = uimenu(app.UIFigure);
            app.KrawedziowanieMenu.MenuSelectedFcn = createCallbackFcn(app, @KrawedziowanieMenuSelected, true);
            app.KrawedziowanieMenu.Text = 'Krawedziowanie';
            app.Poziomypodstawowy1Menu = uimenu(app.KrawedziowanieMenu);
            app.Poziomypodstawowy1Menu.MenuSelectedFcn = createCallbackFcn(app, @Poziomypodstawowy1MenuSelected, true);
            app.Poziomypodstawowy1Menu.Text = 'Poziomy podstawowy 1';
            app.Poziomywzmocniony1Menu = uimenu(app.KrawedziowanieMenu);
            app.Poziomywzmocniony1Menu.MenuSelectedFcn = createCallbackFcn(app, @Poziomywzmocniony1MenuSelected, true);
            app.Poziomywzmocniony1Menu.Text = 'Poziomy wzmocniony 1';
            app.Poziomypodstawowy2Menu = uimenu(app.KrawedziowanieMenu);
            app.Poziomypodstawowy2Menu.MenuSelectedFcn = createCallbackFcn(app, @Poziomypodstawowy2MenuSelected, true);
            app.Poziomypodstawowy2Menu.Text = 'Poziomy podstawowy 2';
            app.Poziomywzmocniony2Menu = uimenu(app.KrawedziowanieMenu);
            app.Poziomywzmocniony2Menu.MenuSelectedFcn = createCallbackFcn(app, @Poziomywzmocniony2MenuSelected, true);
            app.Poziomywzmocniony2Menu.Text = 'Poziomy wzmocniony 2';
            app.Pionowypodstawowy1Menu = uimenu(app.KrawedziowanieMenu);
            app.Pionowypodstawowy1Menu.MenuSelectedFcn = createCallbackFcn(app, @Pionowypodstawowy1MenuSelected, true);
            app.Pionowypodstawowy1Menu.Text = 'Pionowy podstawowy 1';
            app.Pionowywzmocniony1Menu = uimenu(app.KrawedziowanieMenu);
            app.Pionowywzmocniony1Menu.MenuSelectedFcn = createCallbackFcn(app, @Pionowywzmocniony1MenuSelected, true);
            app.Pionowywzmocniony1Menu.Text = 'Pionowy wzmocniony 1';
            app.Pionowypodstawowy2Menu = uimenu(app.KrawedziowanieMenu);
            app.Pionowypodstawowy2Menu.MenuSelectedFcn = createCallbackFcn(app, @Pionowypodstawowy2MenuSelected, true);
            app.Pionowypodstawowy2Menu.Text = 'Pionowy podstawowy 2';
            app.Pionowypodstawowy2Menu_2 = uimenu(app.KrawedziowanieMenu);
            app.Pionowypodstawowy2Menu_2.MenuSelectedFcn = createCallbackFcn(app, @Pionowypodstawowy2Menu_2Selected, true);
            app.Pionowypodstawowy2Menu_2.Text = 'Pionowy podstawowy 2';
            app.Skos1Menu = uimenu(app.KrawedziowanieMenu);
            app.Skos1Menu.MenuSelectedFcn = createCallbackFcn(app, @Skos1MenuSelected, true);
            app.Skos1Menu.Text = 'Skos 1';
            app.Skos2Menu = uimenu(app.KrawedziowanieMenu);
            app.Skos2Menu.MenuSelectedFcn = createCallbackFcn(app, @Skos2MenuSelected, true);
            app.Skos2Menu.Text = 'Skos 2';
            app.Skos3Menu = uimenu(app.KrawedziowanieMenu);
            app.Skos3Menu.MenuSelectedFcn = createCallbackFcn(app, @Skos3MenuSelected, true);
            app.Skos3Menu.Text = 'Skos 3';
            app.Skos4Menu = uimenu(app.KrawedziowanieMenu);
            app.Skos4Menu.MenuSelectedFcn = createCallbackFcn(app, @Skos4MenuSelected, true);
            app.Skos4Menu.Text = 'Skos 4';
            app.Skoswzmocniony1Menu = uimenu(app.KrawedziowanieMenu);
            app.Skoswzmocniony1Menu.MenuSelectedFcn = createCallbackFcn(app, @Skoswzmocniony1MenuSelected, true);
            app.Skoswzmocniony1Menu.Text = 'Skos wzmocniony 1';
            app.Skoswzmocniony2Menu = uimenu(app.KrawedziowanieMenu);
            app.Skoswzmocniony2Menu.MenuSelectedFcn = createCallbackFcn(app, @Skoswzmocniony2MenuSelected, true);
            app.Skoswzmocniony2Menu.Text = 'Skos wzmocniony 2';
            app.Skoswzmocniony3Menu = uimenu(app.KrawedziowanieMenu);
            app.Skoswzmocniony3Menu.MenuSelectedFcn = createCallbackFcn(app, @Skoswzmocniony3MenuSelected, true);
            app.Skoswzmocniony3Menu.Text = 'Skos wzmocniony 3';
            app.Skoswzmocniony4Menu = uimenu(app.KrawedziowanieMenu);
            app.Skoswzmocniony4Menu.MenuSelectedFcn = createCallbackFcn(app, @Skoswzmocniony4MenuSelected, true);
            app.Skoswzmocniony4Menu.Text = 'Skos wzmocniony 4';
            app.KierunkowewszystkieMenu = uimenu(app.KrawedziowanieMenu);
            app.KierunkowewszystkieMenu.MenuSelectedFcn = createCallbackFcn(app, @KierunkowewszystkieMenuSelected, true);
            app.KierunkowewszystkieMenu.Text = 'Kierunkowe wszystkie';
            app.DetektorcechMenu = uimenu(app.UIFigure);
            app.DetektorcechMenu.Text = 'Detektor cech';
            app.CechyHarrisMenu = uimenu(app.DetektorcechMenu);
            app.CechyHarrisMenu.MenuSelectedFcn = createCallbackFcn(app, @CechyHarrisMenuSelected, true);
            app.CechyHarrisMenu.Text = 'Cechy Harris';
            app.CechyFastMenu = uimenu(app.DetektorcechMenu);
            app.CechyFastMenu.MenuSelectedFcn = createCallbackFcn(app, @CechyFastMenuSelected, true);
            app.CechyFastMenu.Text = 'Cechy Fast';
            app.CechyKazeMenu = uimenu(app.DetektorcechMenu);
            app.CechyKazeMenu.MenuSelectedFcn = createCallbackFcn(app, @CechyKazeMenuSelected, true);
            app.CechyKazeMenu.Text = 'Cechy Kaze';
            app.MSER1Menu = uimenu(app.DetektorcechMenu);
            app.MSER1Menu.MenuSelectedFcn = createCallbackFcn(app, @MSER1MenuSelected, true);
            app.MSER1Menu.Text = 'MSER 1';
            app.MSER2Menu = uimenu(app.DetektorcechMenu);
            app.MSER2Menu.MenuSelectedFcn = createCallbackFcn(app, @MSER2MenuSelected, true);
            app.MSER2Menu.Text = 'MSER 2';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.thresholdingSliderLabel = uilabel(app.UIFigure);
            app.thresholdingSliderLabel.Text = 'thresholding';
            app.thresholdingSlider = uislider(app.UIFigure);
            app.thresholdingSlider.Limits = [0 255];
            app.thresholdingSlider.ValueChangingFcn = createCallbackFcn(app, @thresholdingSliderValueChanging, true);
            app.thresholdingSlider.Value = 120;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end