classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Menu              matlab.ui.container.Menu
        WczytajObrazMenu  matlab.ui.container.Menu
        ZapiszObrazMenu   matlab.ui.container.Menu
        KluczowanieMenu   matlab.ui.container.Menu
        ProbkujMenu       matlab.ui.container.Menu
        PodminMenu        matlab.ui.container.Menu
        SliderRLabel      matlab.ui.control.Label
        SliderR           matlab.ui.control.Slider
        SliderGLabel      matlab.ui.control.Label
        SliderG           matlab.ui.control.Slider
        SliderBLabel      matlab.ui.control.Label
        SliderB           matlab.ui.control.Slider
        UIAxes            matlab.ui.control.UIAxes
        UIAxes_2          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: WczytajObrazMenu
        function WczytajObrazMenuSelected(app, event)

            global obraz progR progG progB;
            [plik, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz = imread([sciezka,plik]);
            obraz = imresize(obraz, [480,640]);
            imshow(obraz, 'Parent', app.UIAxes)
            progR = 15;
            progG = 15;
            progB = 15;

        end

        % Menu selected function: ProbkujMenu
        function ProbkujMenuSelected(app, event)
            global obraz progR progG progB obraz1 minR maxR minG maxG minB maxB;
            obraz1 = obraz;
            tab = impixel(obraz);
            
            disp(tab);
            minR = min(tab(:,1));
            maxR = max(tab(:,1));
            minG = min(tab(:,2));
            maxG = max(tab(:,2));
            minB = min(tab(:,3));
            maxB = max(tab(:,3));
            
            [a, b, c] = size(obraz);
            for i = 1 : a
                for j = 1 : b
                    if((obraz(i,j,1) >= minR - progR) && (obraz(i,j,1) <= maxR + progR) && (obraz(i,j,2) >= minG - progG) && (obraz(i,j,2) <= maxG + progG) && (obraz(i,j,3) >= minB - progB) && (obraz(i,j,3) <= maxB + progB))
                        obraz1(i,j,1) = 0;
                        obraz1(i,j,2) = 0;
                        obraz1(i,j,3) = 0;
                    end
                end
            end
            imshow(obraz1, 'Parent', app.UIAxes_2)
        end

        % Value changing function: SliderR
        function SliderRValueChanging(app, event)
            global obraz progR progG progB obraz1 minR maxR minG maxG minB maxB;
            progR = event.Value;
            obraz1 = obraz;
            [a, b, c] = size(obraz);
            for i = 1 : a
                for j = 1 : b
                    if((obraz(i,j,1) >= minR - progR) && (obraz(i,j,1) <= maxR + progR) && (obraz(i,j,2) >= minG - progG) && (obraz(i,j,2) <= maxG + progG) && (obraz(i,j,3) >= minB - progB) && (obraz(i,j,3) <= maxB + progB))
                        obraz1(i,j,1) = 0;
                        obraz1(i,j,2) = 0;
                        obraz1(i,j,3) = 0;
                    end
                end
            end
            imshow(obraz1, 'Parent', app.UIAxes_2)
        end

        % Value changing function: SliderG
        function SliderGValueChanging(app, event)
            global obraz progR progG progB obraz1 minR maxR minG maxG minB maxB;
            progG = event.Value;
            obraz1 = obraz;
            [a, b, c] = size(obraz);
            for i = 1 : a
                for j = 1 : b
                    if((obraz(i,j,1) >= minR - progR) && (obraz(i,j,1) <= maxR + progR) && (obraz(i,j,2) >= minG - progG) && (obraz(i,j,2) <= maxG + progG) && (obraz(i,j,3) >= minB - progB) && (obraz(i,j,3) <= maxB + progB))
                        obraz1(i,j,1) = 0;
                        obraz1(i,j,2) = 0;
                        obraz1(i,j,3) = 0;
                    end
                end
            end
            imshow(obraz1, 'Parent', app.UIAxes_2)
            
        end

        % Value changing function: SliderB
        function SliderBValueChanging(app, event)
            global obraz progR progG progB obraz1 minR maxR minG maxG minB maxB;
            progB = event.Value;
            obraz1 = obraz;
            [a, b, c] = size(obraz);
            for i = 1 : a
                for j = 1 : b
                    if((obraz(i,j,1) >= minR - progR) && (obraz(i,j,1) <= maxR + progR) && (obraz(i,j,2) >= minG - progG) && (obraz(i,j,2) <= maxG + progG) && (obraz(i,j,3) >= minB - progB) && (obraz(i,j,3) <= maxB + progB))
                        obraz1(i,j,1) = 0;
                        obraz1(i,j,2) = 0;
                        obraz1(i,j,3) = 0;
                    end
                end
            end
            imshow(obraz1, 'Parent', app.UIAxes_2)
            
        end

        % Menu selected function: PodminMenu
        function PodminMenuSelected(app, event)
            global obraz obraz1 obraz2;
            obraz2 = obraz;
            [plik, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obrazT = imread([sciezka,plik]);
            obrazT = imresize(obrazT, [480,640]);
            
            [a, b, c] = size(obraz1);
            for i = 1 : a
                for j = 1 : b
                    if((obraz1(i,j,1) == 0) && (obraz1(i,j,2) == 0) && (obraz1(i,j,3) == 0))
                        obraz2(i,j,1) = obrazT(i,j,1);
                        obraz2(i,j,2) = obrazT(i,j,2);
                        obraz2(i,j,3) = obrazT(i,j,3);
                    end
                end
            end
            imshow(obraz2, 'Parent', app.UIAxes_2)
        end

        % Menu selected function: ZapiszObrazMenu
        function ZapiszObrazMenuSelected(app, event)
            global obraz2;
            [plik, sciezka] = uiputfile('*.jpg;*.png;*.bmp','Zapisz obraz');
            imwrite(obraz2, [sciezka, plik]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.WczytajObrazMenu = uimenu(app.Menu);
            app.WczytajObrazMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajObrazMenuSelected, true);
            app.WczytajObrazMenu.Text = 'WczytajObraz';
            app.ZapiszObrazMenu = uimenu(app.Menu);
            app.ZapiszObrazMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszObrazMenuSelected, true);
            app.ZapiszObrazMenu.Text = 'Zapisz Obraz';
            app.KluczowanieMenu = uimenu(app.UIFigure);
            app.KluczowanieMenu.Text = 'Kluczowanie';
            app.ProbkujMenu = uimenu(app.KluczowanieMenu);
            app.ProbkujMenu.MenuSelectedFcn = createCallbackFcn(app, @ProbkujMenuSelected, true);
            app.ProbkujMenu.Text = 'Probkuj';
            app.PodminMenu = uimenu(app.KluczowanieMenu);
            app.PodminMenu.MenuSelectedFcn = createCallbackFcn(app, @PodminMenuSelected, true);
            app.PodminMenu.Text = 'Podmin';
            app.SliderRLabel = uilabel(app.UIFigure);
            app.SliderRLabel.Text = 'SliderR';
            app.SliderR = uislider(app.UIFigure);
            app.SliderR.ValueChangingFcn = createCallbackFcn(app, @SliderRValueChanging, true);
            app.SliderR.Value = 15;
            app.SliderGLabel = uilabel(app.UIFigure);
            app.SliderGLabel.Text = 'SliderG';
            app.SliderG = uislider(app.UIFigure);
            app.SliderG.ValueChangingFcn = createCallbackFcn(app, @SliderGValueChanging, true);
            app.SliderG.Value = 15;
            app.SliderBLabel = uilabel(app.UIFigure);
            app.SliderBLabel.Text = 'SliderB';
            app.SliderB = uislider(app.UIFigure);
            app.SliderB.ValueChangingFcn = createCallbackFcn(app, @SliderBValueChanging, true);
            app.SliderB.Value = 15;
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end