classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Menu                matlab.ui.container.Menu
        WczytajFilmMenu     matlab.ui.container.Menu
        KonwersjaMenu       matlab.ui.container.Menu
        SkalaszarosciMenu   matlab.ui.container.Menu
        SkalabinarnaMenu    matlab.ui.container.Menu
        DetektorMenu        matlab.ui.container.Menu
        DetekcjapunktwMenu  matlab.ui.container.Menu
        OdtwarzajButton_2   matlab.ui.control.Button
        OdtwarzajButton     matlab.ui.control.Button
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: WczytajFilmMenu
        function WczytajFilmMenuSelected(app, event)
            global film film_obj k
            film_obj=[];
            film=[];
            [nazwa,sciezka]=uigetfile('*.avi;*.mp4','Wczytaj');
            film_obj=VideoReader([sciezka,nazwa]);
            disp(film_obj);
            vidWidth = 320;
            vidHeight = 240;
            film = struct('cdata', zeros(vidHeight, vidWidth, 3, 'uint8'),'colormap',[]);
            k=1;
            while hasFrame(film_obj)
                frame = readFrame(film_obj);
                frame = imresize(frame, [vidHeight, vidWidth]);
                film(k).cdata=frame;
                k=k+1;
            end
            set(app.OdtwarzajButton,'Enable', 'on');
        end

        % Button pushed function: OdtwarzajButton
        function OdtwarzajButtonPushed(app, event)
            global film film_obj k
            for i = 1 : k - 1
                frame = frame2im(film(i));
                imshow(frame, 'Parent', app.UIAxes);
                pause(1/film_obj.FrameRate);
            end
        end

        % Menu selected function: SkalaszarosciMenu
        function SkalaszarosciMenuSelected(app, event)
            global film film1 k
            map = gray;
            film1 = film;
            for i = 1 : k - 1
                frame = frame2im(film(i));
                frame = rgb2gray(frame);
                film1(i) = im2frame(frame, map);
            end
            set(app.OdtwarzajButton_2, 'Enable', 'on');
        end

        % Button pushed function: OdtwarzajButton_2
        function OdtwarzajButton_2Pushed(app, event)
            global film film_obj film1 k
            for i = 1 : k - 1
                frame = frame2im(film1(i));
                imshow(frame, 'Parent', app.UIAxes_2);
                pause(1/film_obj.FrameRate);
            end
        end

        % Menu selected function: SkalabinarnaMenu
        function SkalabinarnaMenuSelected(app, event)
            global film film1 k
            map = gray;
            film1 = film;
            for i = 1 : k - 1
                frame = frame2im(film(i));
                frame = rgb2gray(frame);
                [a,b] = size(frame);
                for m = 1 : a
                    for n = 1 : b
                        if (frame(m,n) > 100)
                            frame(m,n) = 255;
                        else
                            frame(m,n) = 1;
                        end
                    end
                end
                film1(i) = im2frame(frame, map);
            end
            set(app.OdtwarzajButton_2, 'Enable', 'on');
        end

        % Menu selected function: DetekcjapunktwMenu
        function DetekcjapunktwMenuSelected(app, event)
            global film film_obj k
            for i = 1 : k - 2
                frameX = frame2im(film(i));
                frame1 = frame2im(film(i));
                frame1 = rgb2gray(frame1);
                
                frame2 = frame2im(film(i+1));
                frame2 = rgb2gray(frame2);
                
                wynik = abs(frame2 - frame1);
                %-----

                corners = detectFASTFeatures(frame1);
                loc = corners.Location;
                [a, b] = size(loc);
                for j = 2 : a - 1
                    frameX(floor(loc(j, 2)), floor(loc(j, 1)),2) = 255;
                    frameX(floor(loc(j, 2)+1), floor(loc(j, 1)),3) = 255;
                    frameX(floor(loc(j, 2)-1), floor(loc(j, 1)),3) = 255;
                    frameX(floor(loc(j, 2)), floor(loc(j, 1)+1),3) = 255;
                    frameX(floor(loc(j, 2)), floor(loc(j, 1)-1),3) = 255;
                end
                
                imshow(frameX, 'Parent', app.UIAxes_3);


                imshow(wynik, 'Parent', app.UIAxes_2);
                pause(1/film_obj.FrameRate);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.WczytajFilmMenu = uimenu(app.Menu);
            app.WczytajFilmMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajFilmMenuSelected, true);
            app.WczytajFilmMenu.Text = 'Wczytaj Film';
            app.KonwersjaMenu = uimenu(app.UIFigure);
            app.KonwersjaMenu.Text = 'Konwersja';
            app.SkalaszarosciMenu = uimenu(app.KonwersjaMenu);
            app.SkalaszarosciMenu.MenuSelectedFcn = createCallbackFcn(app, @SkalaszarosciMenuSelected, true);
            app.SkalaszarosciMenu.Text = 'Skala szarosci';
            app.SkalabinarnaMenu = uimenu(app.KonwersjaMenu);
            app.SkalabinarnaMenu.MenuSelectedFcn = createCallbackFcn(app, @SkalabinarnaMenuSelected, true);
            app.SkalabinarnaMenu.Text = 'Skala binarna';
            app.DetektorMenu = uimenu(app.UIFigure);
            app.DetektorMenu.Text = 'Detektor';
            app.DetekcjapunktwMenu = uimenu(app.DetektorMenu);
            app.DetekcjapunktwMenu.MenuSelectedFcn = createCallbackFcn(app, @DetekcjapunktwMenuSelected, true);
            app.DetekcjapunktwMenu.Text = 'Detekcja punktów';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.OdtwarzajButton = uibutton(app.UIFigure, 'push');
            app.OdtwarzajButton.ButtonPushedFcn = createCallbackFcn(app, @OdtwarzajButtonPushed, true);
            app.OdtwarzajButton.Enable = 'off';
            app.OdtwarzajButton.Text = 'Odtwarzaj';
            app.OdtwarzajButton_2 = uibutton(app.UIFigure, 'push');
            app.OdtwarzajButton_2.ButtonPushedFcn = createCallbackFcn(app, @OdtwarzajButton_2Pushed, true);
            app.OdtwarzajButton_2.Enable = 'off';
            app.OdtwarzajButton_2.Text = 'Odtwarzaj';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end