classdef projekt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProjektUIFigure           matlab.ui.Figure
        PlikiMenu                 matlab.ui.container.Menu
        WczytajObrazMenu          matlab.ui.container.Menu
        ZapiszObrazMenu           matlab.ui.container.Menu
        WyjcieMenu                matlab.ui.container.Menu
        EfektypodstawoweMenu      matlab.ui.container.Menu
        RozmycieMenu              matlab.ui.container.Menu
        SepiaMenu                 matlab.ui.container.Menu
        PikselizacjaMenu          matlab.ui.container.Menu
        WinietaMenu               matlab.ui.container.Menu
        Moc1Menu                  matlab.ui.container.Menu
        Moc15Menu                 matlab.ui.container.Menu
        Moc2Menu                  matlab.ui.container.Menu
        EfektyartystyczneMenu     matlab.ui.container.Menu
        SzkicowkiemMenu           matlab.ui.container.Menu
        SzkicprostyMenu           matlab.ui.container.Menu
        Szkiczmaskx1Menu          matlab.ui.container.Menu
        Szkiczmaskx2Menu          matlab.ui.container.Menu
        MalarstwoolejneMenu       matlab.ui.container.Menu
        Rozmiarbloku4Menu         matlab.ui.container.Menu
        Rozmiarbloku8Menu         matlab.ui.container.Menu
        Rozmiarbloku16Menu        matlab.ui.container.Menu
        Rozmiarbloku32Menu        matlab.ui.container.Menu
        AkwareleMenu              matlab.ui.container.Menu
        KoloroweszkoMenu          matlab.ui.container.Menu
        Rozmiarkafelka4Menu       matlab.ui.container.Menu
        Rozmiarkafelka6Menu       matlab.ui.container.Menu
        Rozmiarkafelka12Menu      matlab.ui.container.Menu
        ChromatycznerozmycieMenu  matlab.ui.container.Menu
        Przesuniecieo5Menu        matlab.ui.container.Menu
        Przesunicioo15Menu        matlab.ui.container.Menu
        NeonMenu                  matlab.ui.container.Menu
        PlakatMenu                matlab.ui.container.Menu
        Iloodcienii4Menu          matlab.ui.container.Menu
        Iloodcienii8Menu          matlab.ui.container.Menu
        Iloodcienii10Menu         matlab.ui.container.Menu
        FaleMenu                  matlab.ui.container.Menu
        SabeMenu                  matlab.ui.container.Menu
        rednieMenu                matlab.ui.container.Menu
        MocneMenu                 matlab.ui.container.Menu
        EfektogniaMenu            matlab.ui.container.Menu
        StarezdjcieMenu           matlab.ui.container.Menu
        ResetButton               matlab.ui.control.Button
        ZapiszefektyButton        matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        InformacjeTab             matlab.ui.container.Tab
        GenerujhistogramButton    matlab.ui.control.Button
        HistogramRGBLabel         matlab.ui.control.Label
        brakLabel_9               matlab.ui.control.Label
        DatamodyfikacjiLabel      matlab.ui.control.Label
        ObrazLabel                matlab.ui.control.Label
        PlikLabel                 matlab.ui.control.Label
        brakLabel_8               matlab.ui.control.Label
        OdwzorowaniekoloruLabel   matlab.ui.control.Label
        brakLabel_7               matlab.ui.control.Label
        GbiawbitachLabel          matlab.ui.control.Label
        brakLabel_6               matlab.ui.control.Label
        FormatLabel               matlab.ui.control.Label
        brakLabel_5               matlab.ui.control.Label
        brakLabel_4               matlab.ui.control.Label
        SzerokoLabel              matlab.ui.control.Label
        WysokoLabel               matlab.ui.control.Label
        brakLabel_3               matlab.ui.control.Label
        RozmiarplikuLabel         matlab.ui.control.Label
        InformacjeowczytanymobrazieLabel  matlab.ui.control.Label
        brakLabel_2               matlab.ui.control.Label
        brakLabel                 matlab.ui.control.Label
        ciekafolderuLabel         matlab.ui.control.Label
        NazwaLabel                matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
        PoprawajakociTab          matlab.ui.container.Tab
        ZapiszButton_3            matlab.ui.control.Button
        UsuwanieszumwLabel        matlab.ui.control.Label
        WartoSlider_2             matlab.ui.control.Slider
        WartoSlider_2Label        matlab.ui.control.Label
        WyostrzenieLabel          matlab.ui.control.Label
        ZapiszButton_2            matlab.ui.control.Button
        WartoSlider               matlab.ui.control.Slider
        WartoSliderLabel          matlab.ui.control.Label
        RestartButton             matlab.ui.control.Button
        ZapiszButton              matlab.ui.control.Button
        JasnoikontrastLabel       matlab.ui.control.Label
        WartoMinSlider            matlab.ui.control.Slider
        WartoMinSliderLabel       matlab.ui.control.Label
        WartoMaxSlider            matlab.ui.control.Slider
        WartoMaxSliderLabel       matlab.ui.control.Label
        KorektakolorwTab          matlab.ui.container.Tab
        RestartButton_2           matlab.ui.control.Button
        ZapiszzmianyButton        matlab.ui.control.Button
        KorektabarwHSVLabel       matlab.ui.control.Label
        SaturationSlider_2        matlab.ui.control.Slider
        SaturationSlider_2Label   matlab.ui.control.Label
        ValueSlider               matlab.ui.control.Slider
        ValueSliderLabel          matlab.ui.control.Label
        HueSlider                 matlab.ui.control.Slider
        HueSliderLabel            matlab.ui.control.Label
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: WczytajObrazMenu
        function WczytajObrazMenuSelected(app, event)
            global obraz obraz_kopia adjust_min adjust_max hue saturation val obraz_efekt
            adjust_max = 1;
            adjust_min = 0;
            hue = 0;
            saturation = 1;
            val = 1;
            [plik, sciezka] = uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            

            if isequal(plik, 0) || isequal(sciezka, 0)
                uialert(app.ProjektUIFigure, 'Wystąpił błąd podczas wczytania obrazu', 'Błąd');
            else
                
                cla(app.UIAxes2);
                info = imfinfo([sciezka,plik]);
                app.brakLabel.Text = plik;
                app.brakLabel_2.Text = info.Filename;
                app.brakLabel_3.Text = [num2str(round(info.FileSize/1024)), ' Kb'];
                app.brakLabel_4.Text = num2str(info.Height);
                app.brakLabel_5.Text = num2str(info.Width);
                app.brakLabel_6.Text = info.Format;
                app.brakLabel_7.Text = num2str(info.BitDepth);
                app.brakLabel_8.Text = info.ColorType;
                app.brakLabel_9.Text = info.FileModDate;

                app.WartoMaxSlider.Enable = true;
                app.WartoMinSlider.Enable = true;
                app.WartoSlider.Enable = true;
                app.WartoSlider_2.Enable = true;

                obraz = imread([sciezka,plik]);
                obraz_kopia = obraz;
                obraz_efekt = obraz;
                imshow(obraz, 'Parent', app.UIAxes);
            end
            
        end

        % Menu selected function: ZapiszObrazMenu
        function ZapiszObrazMenuSelected(app, event)
            global obraz;
            [plik, sciezka] = uiputfile('*.jpg;*.png;*.bmp','Zapisz obraz');
            
            if isequal(plik, 0) || isequal(sciezka, 0)
                msgbox('Wystąpił błąd podczas zapisywania obrazu', 'Error','error');
            else
                imwrite(obraz, [sciezka, plik]);
            
                d = dialog('Position',[300 300 250 150],'Name','Zapisywanie obrazu');
                txt = uicontrol('Parent',d,'Style','text','Position',[20 80 210 40], 'String','Obraz został pomyślnie zapisany');
                btn = uicontrol('Parent',d,'Position',[85 40 70 25], 'String', 'Zamknij', 'Callback','delete(gcf)');
            end
            
        end

        % Menu selected function: WyjcieMenu
        function WyjcieMenuSelected(app, event)
            close(app.ProjektUIFigure);
        end

        % Value changing function: WartoMaxSlider
        function WartoMaxSliderValueChanging(app, event)
            global obraz obraz_new adjust_min adjust_max
            adjust_max = event.Value;
            if(adjust_max == 0.5)
                adjust_max = 0.501;
            end
            obraz_new = imadjust(obraz, [adjust_min adjust_max]);
            imshow(obraz_new, 'Parent', app.UIAxes2);
        end

        % Value changing function: WartoMinSlider
        function WartoMinSliderValueChanging(app, event)
            global obraz obraz_new adjust_min adjust_max
            adjust_min = event.Value;
            obraz_new = imadjust(obraz, [adjust_min adjust_max]);
            imshow(obraz_new, 'Parent', app.UIAxes2);
        end

        % Callback function
        function WyostrzobrazButtonPushed(app, event)
            global obraz obraz_new
            obraz_new = imsharpen(obraz_new);
            imshow(obraz_new, 'Parent', app.UIAxes2);
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
            global obraz adjust_min adjust_max
            obraz = imadjust(obraz, [adjust_min adjust_max]);
            app.WartoMaxSlider.Enable = false;
            app.WartoMinSlider.Enable = false;
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Button pushed function: ResetButton, RestartButton, 
        % RestartButton_2
        function RestartButtonPushed(app, event)
            global obraz obraz_kopia adjust_min adjust_max hue saturation val
            choice = uiconfirm(app.ProjektUIFigure, ...
                'Czy na pewno chcesz zresetować wartości?', ...
                'Potwierdzenie', ...
                'Options', {'Tak', 'Nie'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
        
            if strcmp(choice, 'Tak')
                adjust_max = 1;
                adjust_min = 0;
                app.WartoMaxSlider.Enable = true;
                app.WartoMinSlider.Enable = true;
                app.ZapiszzmianyButton.Enable = true;
                app.HueSlider.Enable = true;
                app.SaturationSlider_2.Enable = true;
                app.ValueSlider.Enable = true;
                hue = 0;
                saturation = 1;
                val = 1;
    
                obraz = obraz_kopia;
                imshow(obraz, 'Parent', app.UIAxes2);
            else
                uialert(app.ProjektUIFigure, 'Reset został anulowany.', 'Anulowano');
             end   
        end

        % Value changing function: WartoSlider
        function WartoSliderValueChanging(app, event)

        end

        % Value changed function: WartoSlider
        function WartoSliderValueChanged(app, event)
            global obraz sharpen_amount
            sharpen_amount = app.WartoSlider.Value;
            obraz_new = imsharpen(obraz, 'Radius', 3, 'Amount', sharpen_amount);
            imshow(obraz_new, 'Parent', app.UIAxes2);
        end

        % Button pushed function: ZapiszButton_2
        function ZapiszButton_2Pushed(app, event)
            global obraz sharpen_amount
            obraz = imsharpen(obraz, 'Radius', 3, 'Amount', sharpen_amount);
            app.WartoSlider.Enable = false;
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Value changing function: WartoSlider_2
        function WartoSlider_2ValueChanging(app, event)
            global obraz sigma
            sigma = event.Value;
            if sigma == 0
                sigma = 0.001;
            end
            obraz_new = imgaussfilt(obraz, sigma);
            imshow(obraz_new, 'Parent', app.UIAxes2);
        end

        % Button pushed function: ZapiszButton_3
        function ZapiszButton_3Pushed(app, event)
            global obraz sigma
            obraz = imgaussfilt(obraz, sigma);
            app.WartoSlider_2.Enable = false;
            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Button pushed function: GenerujhistogramButton
        function GenerujhistogramButtonPushed(app, event)
            global obraz
            R = obraz(:, :, 1);
            G = obraz(:, :, 2);
            B = obraz(:, :, 3);
            
            histogramR = imhist(R);
            histogramG = imhist(G);
            histogramB = imhist(B);

            cla(app.UIAxes3);

            hold(app.UIAxes3, 'on');
            bar(app.UIAxes3, histogramR, 'r');
            bar(app.UIAxes3, histogramG, 'g');
            bar(app.UIAxes3, histogramB, 'b');
            hold(app.UIAxes3, 'off');
        end

        % Value changing function: HueSlider
        function HueSliderValueChanging(app, event)
            global obraz hsvObraz hue saturation val
            hue = event.Value;
            hsvObraz = rgb2hsv(obraz);
            hsvObraz(:, :, 1) = hsvObraz(:, :, 1) + hue;
            hsvObraz(:, :, 2) = hsvObraz(:, :, 2) * saturation;
            hsvObraz(:, :, 3) = hsvObraz(:, :, 3) * val;
            imshow(hsv2rgb(hsvObraz), 'Parent', app.UIAxes2);
        end

        % Value changing function: SaturationSlider_2
        function SaturationSlider_2ValueChanging(app, event)
            global obraz hsvObraz hue saturation val
            
            saturation = event.Value;
            hsvObraz = rgb2hsv(obraz);
            hsvObraz(:, :, 1) = hsvObraz(:, :, 1) + hue;
            hsvObraz(:, :, 2) = hsvObraz(:, :, 2) * saturation;
            hsvObraz(:, :, 3) = hsvObraz(:, :, 3) * val;
            imshow(hsv2rgb(hsvObraz), 'Parent', app.UIAxes2);
        end

        % Value changing function: ValueSlider
        function ValueSliderValueChanging(app, event)
            global obraz hsvObraz hue saturation val
            val = event.Value;
            hsvObraz = rgb2hsv(obraz);
            hsvObraz(:, :, 1) = hsvObraz(:, :, 1) + hue;
            hsvObraz(:, :, 2) = hsvObraz(:, :, 2) * saturation;
            hsvObraz(:, :, 3) = hsvObraz(:, :, 3) * val;

            imshow(hsv2rgb(hsvObraz), 'Parent', app.UIAxes2);
        end

        % Button pushed function: ZapiszzmianyButton
        function ZapiszzmianyButtonPushed(app, event)
            global obraz hsvObraz hue saturation val
            hsvObraz = rgb2hsv(obraz);
            hsvObraz(:, :, 1) = hsvObraz(:, :, 1) + hue;
            hsvObraz(:, :, 2) = hsvObraz(:, :, 2) * saturation;
            hsvObraz(:, :, 3) = hsvObraz(:, :, 3) * val;
            app.ZapiszzmianyButton.Enable = false;
            app.HueSlider.Enable = false;
            app.SaturationSlider_2.Enable = false;
            app.ValueSlider.Enable = false;
            obraz = hsv2rgb(hsvObraz);

            imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Menu selected function: AkwareleMenu
        function AkwareleMenuSelected(app, event)
            global obraz
            blurredImage = imgaussfilt(obraz, 2);
            edges = edge(rgb2gray(obraz), 'Canny') - 0.8;
            watercolorImage = blurredImage - uint8(255 * edges);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(watercolorImage, 'Parent', app.UIAxes2);
        end

        % Menu selected function: SzkicprostyMenu
        function SzkicprostyMenuSelected(app, event)
            global obraz obraz_efekt
            if size(obraz, 3) == 3
               obrazGray = rgb2gray(obraz);
            end

            edges = edge(obrazGray, 'Canny');
            obraz_efekt = imcomplement(edges);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Szkiczmaskx1Menu
        function Szkiczmaskx1MenuSelected(app, event)
            global obraz obraz_efekt
            if size(obraz, 3) == 3
                obrazGray = rgb2gray(obraz);
            end

            h2 = [0 1 0;1 -4 1;0 1 0];
            imagf = imfilter( obrazGray, h2, 'conv');
            obraz_efekt = 255-imagf;

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Szkiczmaskx2Menu
        function Szkiczmaskx2MenuSelected(app, event)
            global obraz obraz_efekt
            if size(obraz, 3) == 3
                obrazGray = rgb2gray(obraz);
            end

            h2 =   [0 0 1 1 0 0;
                    0 0 1 1 0 0;
                    1 1 -4 -4 1 1;
                    1 1 -4 -4 1 1;
                    0 0 1 1 0 0;
                    0 0 1 1 0 0];
            imagf = imfilter( obrazGray, h2, 'conv');
            obraz_efekt = 255-imagf;

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Rozmiarbloku4Menu
        function Rozmiarbloku4MenuSelected(app, event)
            global obraz obraz_efekt
            filterSize = 4;
            % Podział na kanały R, G, B
            R = obraz(:, :, 1);
            G = obraz(:, :, 2);
            B = obraz(:, :, 3);
    
            R_out = nlfilter(R, [filterSize filterSize], @(x) median(x(:)));
            G_out = nlfilter(G, [filterSize filterSize], @(x) median(x(:)));
            B_out = nlfilter(B, [filterSize filterSize], @(x) median(x(:)));
    
            obraz_efekt = cat(3, R_out, G_out, B_out);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Rozmiarbloku8Menu
        function Rozmiarbloku8MenuSelected(app, event)
            global obraz obraz_efekt
            filterSize = 8;
            % Podział na kanały R, G, B
            R = obraz(:, :, 1);
            G = obraz(:, :, 2);
            B = obraz(:, :, 3);
    
            R_out = nlfilter(R, [filterSize filterSize], @(x) median(x(:)));
            G_out = nlfilter(G, [filterSize filterSize], @(x) median(x(:)));
            B_out = nlfilter(B, [filterSize filterSize], @(x) median(x(:)));
    
            obraz_efekt = cat(3, R_out, G_out, B_out);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Rozmiarbloku16Menu
        function Rozmiarbloku16MenuSelected(app, event)
            global obraz obraz_efekt
            filterSize = 16;
            % Podział na kanały R, G, B
            R = obraz(:, :, 1);
            G = obraz(:, :, 2);
            B = obraz(:, :, 3);
    
            R_out = nlfilter(R, [filterSize filterSize], @(x) median(x(:)));
            G_out = nlfilter(G, [filterSize filterSize], @(x) median(x(:)));
            B_out = nlfilter(B, [filterSize filterSize], @(x) median(x(:)));
    
            obraz_efekt = cat(3, R_out, G_out, B_out);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Rozmiarbloku32Menu
        function Rozmiarbloku32MenuSelected(app, event)
            global obraz obraz_efekt
            filterSize = 32;
            % Podział na kanały R, G, B
            R = obraz(:, :, 1);
            G = obraz(:, :, 2);
            B = obraz(:, :, 3);
    
            R_out = nlfilter(R, [filterSize filterSize], @(x) median(x(:)));
            G_out = nlfilter(G, [filterSize filterSize], @(x) median(x(:)));
            B_out = nlfilter(B, [filterSize filterSize], @(x) median(x(:)));
    
            obraz_efekt = cat(3, R_out, G_out, B_out);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Callback function
        function Menu2Selected(app, event)
            global obraz obraz_efekt
            tileSize = 10;
            [rows, cols, ~] = size(app.Obraz);
            for i = 1:tileSize:rows
                for j = 1:tileSize:cols
                    colorShift = rand(1, 3);
                    obraz(i:min(i+tileSize-1, rows), j:min(j+tileSize-1, cols), :) = ...
                        obraz(i:min(i+tileSize-1, rows), j:min(j+tileSize-1, cols), :) + colorShift;
                end
            end

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
        imshow(obraz, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Rozmiarkafelka4Menu
        function Rozmiarkafelka4MenuSelected(app, event)
            global obraz obraz_efekt
            tileSize = 4;
            [rows, cols, ~] = size(obraz);
            for i = 1:tileSize:rows
                for j = 1:tileSize:cols
                    block = obraz(i:min(i+tileSize-1, rows), j:min(j+tileSize-1, cols), :);
                    block = double(block);
    
                    colorShift = rand(1, 1, 3) * 50;
                    block = block + colorShift;
                    block = max(0, min(255, block));
    
                    obraz_efekt(i:min(i+tileSize-1, rows), j:min(j+tileSize-1, cols), :) = uint8(block);
                end
            end

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Rozmiarkafelka6Menu
        function Rozmiarkafelka6MenuSelected(app, event)
            global obraz obraz_efekt
            tileSize = 6;
            [rows, cols, ~] = size(obraz);
            for i = 1:tileSize:rows
                for j = 1:tileSize:cols
                    block = obraz(i:min(i+tileSize-1, rows), j:min(j+tileSize-1, cols), :);
                    block = double(block);
    
                    colorShift = rand(1, 1, 3) * 50;
                    block = block + colorShift;
                    block = max(0, min(255, block));
    
                    obraz_efekt(i:min(i+tileSize-1, rows), j:min(j+tileSize-1, cols), :) = uint8(block);
                end
            end

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Rozmiarkafelka12Menu
        function Rozmiarkafelka12MenuSelected(app, event)
            global obraz obraz_efekt
            tileSize = 12;
            [rows, cols, ~] = size(obraz);
            for i = 1:tileSize:rows
                for j = 1:tileSize:cols
                    block = obraz(i:min(i+tileSize-1, rows), j:min(j+tileSize-1, cols), :);
                    block = double(block);
    
                    colorShift = rand(1, 1, 3) * 50;
                    block = block + colorShift;
                    block = max(0, min(255, block));
    
                    obraz_efekt(i:min(i+tileSize-1, rows), j:min(j+tileSize-1, cols), :) = uint8(block);
                end
            end

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: ChromatycznerozmycieMenu
        function ChromatycznerozmycieMenuSelected(app, event)
           
        end

        % Menu selected function: Przesuniecieo5Menu
        function Przesuniecieo5MenuSelected(app, event)
             global obraz obraz_efekt
            shift = 5;
            rChannel = circshift(obraz(:, :, 1), [0, shift]); 
            gChannel = circshift(obraz(:, :, 2), [shift, 0]);
            bChannel = obraz(:, :, 3); 
            chromaticImage = cat(3, rChannel, gChannel, bChannel);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(chromaticImage, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Przesunicioo15Menu
        function Przesunicioo15MenuSelected(app, event)
             global obraz obraz_efekt
            shift = 15;
            rChannel = circshift(obraz(:, :, 1), [0, shift]); 
            gChannel = circshift(obraz(:, :, 2), [shift, 0]); 
            bChannel = obraz(:, :, 3);
            chromaticImage = cat(3, rChannel, gChannel, bChannel);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(chromaticImage, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Iloodcienii4Menu
        function Iloodcienii4MenuSelected(app, event)
            global obraz obraz_efekt
            numLevels = 4;
            obrazN = double(obraz) / 255;
            posterImage = round(obrazN * (numLevels - 1)) / (numLevels - 1);
            posterImage = uint8(posterImage * 255);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(posterImage, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Iloodcienii8Menu
        function Iloodcienii8MenuSelected(app, event)
            global obraz obraz_efekt
            numLevels = 8;
            obrazN = double(obraz) / 255;
            posterImage = round(obrazN * (numLevels - 1)) / (numLevels - 1);
            posterImage = uint8(posterImage * 255);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(posterImage, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Iloodcienii10Menu
        function Iloodcienii10MenuSelected(app, event)
            global obraz obraz_efekt
            numLevels = 10;
            obrazN = double(obraz) / 255;
            posterImage = round(obrazN * (numLevels - 1)) / (numLevels - 1);
            posterImage = uint8(posterImage * 255);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(posterImage, 'Parent', app.UIAxes2);
        end

        % Menu selected function: SabeMenu
        function SabeMenuSelected(app, event)
            global obraz obraz_efekt
            amp = 6;
            freq = 0.08;
            [h,w,~] = size(obraz);
            dx = repmat(amp*cos(freq*(1:h)'),[1 w]);
            dy = zeros(h,w);
            
            FG = imwarp(obraz,cat(3,dx,dy));
            
            FG = im2double(FG);
            BG = im2double(obraz);
            obraz_efekt = FG.*FG + BG.*(1-FG);
            obraz_efekt = im2uint8(obraz_efekt);
            
            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: rednieMenu
        function rednieMenuSelected(app, event)
            global obraz obraz_efekt
            amp = 12;
            freq = 0.06;
            [h,w,~] = size(obraz);
            dx = repmat(amp*cos(freq*(1:h)'),[1 w]);
            dy = zeros(h,w);
            
            FG = imwarp(obraz,cat(3,dx,dy));
            
            FG = im2double(FG);
            BG = im2double(obraz);
            obraz_efekt = FG.*FG + BG.*(1-FG);
            obraz_efekt = im2uint8(obraz_efekt);
            
            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: MocneMenu
        function MocneMenuSelected(app, event)
            global obraz obraz_efekt
            amp = 24;
            freq = 0.06;
            [h,w,~] = size(obraz);
            dx = repmat(amp*cos(freq*(1:h)'),[1 w]);
            dy = zeros(h,w);
            
            FG = imwarp(obraz,cat(3,dx,dy));
            
            FG = im2double(FG);
            BG = im2double(obraz);
            obraz_efekt = FG.*FG + BG.*(1-FG);
            obraz_efekt = im2uint8(obraz_efekt);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: RozmycieMenu
        function RozmycieMenuSelected(app, event)
            global obraz obraz_efekt
            h = fspecial('gaussian', [5 5], 2); % Filtr 5x5
            obraz_efekt = imfilter(obraz, h);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: SepiaMenu
        function SepiaMenuSelected(app, event)
            global obraz obraz_efekt
            grayImage = rgb2gray(obraz);
            obraz_efekt = cat(3, grayImage * 0.9, grayImage * 0.7, grayImage * 0.4);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: PikselizacjaMenu
        function PikselizacjaMenuSelected(app, event)
            global obraz obraz_efekt
            smallImage = imresize(obraz, 0.1);
            obraz_efekt = imresize(smallImage, size(obraz, 1:2));

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: EfektogniaMenu
        function EfektogniaMenuSelected(app, event)
            global obraz obraz_efekt
            grayImage = rgb2gray(obraz);
            obraz_efekt = ind2rgb(grayImage, hot(256));

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: StarezdjcieMenu
        function StarezdjcieMenuSelected(app, event)
            global obraz obraz_efekt
            noiseImage = imnoise(obraz, 'gaussian', 0, 0.01);
            grayImage = rgb2gray(noiseImage);
            obraz_efekt = cat(3, grayImage * 0.8, grayImage * 0.7, grayImage * 0.5);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Moc1Menu
        function Moc1MenuSelected(app, event)
            global obraz obraz_efekt

            [rows, cols, ~] = size(obraz);
            [X, Y] = meshgrid(1:cols, 1:rows);
            
            centerX = cols / 2; centerY = rows / 2;
            sigma = min(rows, cols) / 1.15;
            vignetteMask = exp(-((X - centerX).^2 + (Y - centerY).^2) / (2 * sigma^2));
            
            obraz_double = im2double(obraz);
            obraz_efekt = obraz_double .* vignetteMask;

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Moc15Menu
        function Moc15MenuSelected(app, event)
            global obraz obraz_efekt

            [rows, cols, ~] = size(obraz);
            [X, Y] = meshgrid(1:cols, 1:rows);
            
            centerX = cols / 2; centerY = rows / 2;
            sigma = min(rows, cols) / 1.5;
            vignetteMask = exp(-((X - centerX).^2 + (Y - centerY).^2) / (2 * sigma^2));
            
            obraz_double = im2double(obraz);
            obraz_efekt = obraz_double .* vignetteMask;

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: Moc2Menu
        function Moc2MenuSelected(app, event)
            global obraz obraz_efekt

            [rows, cols, ~] = size(obraz);
            [X, Y] = meshgrid(1:cols, 1:rows);
            
            centerX = cols / 2; centerY = rows / 2;
            sigma = min(rows, cols) / 1.8;
            vignetteMask = exp(-((X - centerX).^2 + (Y - centerY).^2) / (2 * sigma^2));
            
            obraz_double = im2double(obraz);
            obraz_efekt = obraz_double .* vignetteMask;

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Menu selected function: NeonMenu
        function NeonMenuSelected(app, event)
            global obraz obraz_efekt
            obraz_blur = imgaussfilt(obraz, 3);
            grayImage = rgb2gray(obraz_blur);
            
            edges = edge(grayImage, 'Canny', 0.05, 2);
            
            [rows, cols] = size(edges);
            neonEffect = zeros(rows, cols, 3, 'uint8');
            
            neonEffect(:, :, 1) = uint8(edges) * 255;
            neonEffect(:, :, 2) = uint8(imdilate(edges, strel('disk', 1))) * 100;
            neonEffect(:, :, 3) = uint8(imdilate(edges, strel('disk', 2))) * 200;
            neonBlend = imadd(im2uint8(im2double(obraz) * 0.3), neonEffect);
    
            motionKernel = fspecial('motion', 20, 45);
            motionBlur = imfilter(neonBlend, motionKernel, 'replicate');
    
            radialBlurMask = uint8(imgaussfilt(double(edges), 5) * 255);
            radialBlur = imgaussfilt(neonBlend, 10);
            radialBlurEffect = neonBlend;
            radialBlurEffect(repmat(radialBlurMask > 50, [1 1 3])) = radialBlur(repmat(radialBlurMask > 50, [1 1 3]));
    
            obraz_efekt = imadd(motionBlur, radialBlurEffect);

            app.ResetButton.Enable = true;
            app.ZapiszefektyButton.Enable = true;
            imshow(obraz_efekt, 'Parent', app.UIAxes2);
        end

        % Button pushed function: ZapiszefektyButton
        function ZapiszefektyButtonPushed(app, event)
             global obraz obraz_efekt
             obraz = obraz_efekt;
             d = dialog('Position',[300 300 250 150],'Name','Zapisz efekt');
                txt = uicontrol('Parent',d,'Style','text','Position',[20 80 210 40], 'String','Efekt na obrazie został pomyślnie zapisany');
                btn = uicontrol('Parent',d,'Position',[85 40 70 25], 'String', 'Zamknij', 'Callback','delete(gcf)');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProjektUIFigure = uifigure('Visible', 'off');
            app.ProjektUIFigure.Name = 'Projekt';
            app.PlikiMenu = uimenu(app.ProjektUIFigure);
            app.PlikiMenu.Text = 'Pliki  ';
            app.WczytajObrazMenu = uimenu(app.PlikiMenu);
            app.WczytajObrazMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajObrazMenuSelected, true);
            app.WczytajObrazMenu.Text = 'Wczytaj Obraz';
            app.ZapiszObrazMenu = uimenu(app.PlikiMenu);
            app.ZapiszObrazMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszObrazMenuSelected, true);
            app.ZapiszObrazMenu.Text = 'Zapisz Obraz';
            app.WyjcieMenu = uimenu(app.PlikiMenu);
            app.WyjcieMenu.MenuSelectedFcn = createCallbackFcn(app, @WyjcieMenuSelected, true);
            app.WyjcieMenu.Text = 'Wyjście';
            app.EfektypodstawoweMenu = uimenu(app.ProjektUIFigure);
            app.EfektypodstawoweMenu.Text = 'Efekty podstawowe ';
            app.RozmycieMenu = uimenu(app.EfektypodstawoweMenu);
            app.RozmycieMenu.MenuSelectedFcn = createCallbackFcn(app, @RozmycieMenuSelected, true);
            app.RozmycieMenu.Text = 'Rozmycie';
            app.SepiaMenu = uimenu(app.EfektypodstawoweMenu);
            app.SepiaMenu.MenuSelectedFcn = createCallbackFcn(app, @SepiaMenuSelected, true);
            app.SepiaMenu.Text = 'Sepia';
            app.PikselizacjaMenu = uimenu(app.EfektypodstawoweMenu);
            app.PikselizacjaMenu.MenuSelectedFcn = createCallbackFcn(app, @PikselizacjaMenuSelected, true);
            app.PikselizacjaMenu.Text = 'Pikselizacja';
            app.WinietaMenu = uimenu(app.EfektypodstawoweMenu);
            app.WinietaMenu.Text = 'Winieta';
            app.Moc1Menu = uimenu(app.WinietaMenu);
            app.Moc1Menu.MenuSelectedFcn = createCallbackFcn(app, @Moc1MenuSelected, true);
            app.Moc1Menu.Text = 'Moc 1';
            app.Moc15Menu = uimenu(app.WinietaMenu);
            app.Moc15Menu.MenuSelectedFcn = createCallbackFcn(app, @Moc15MenuSelected, true);
            app.Moc15Menu.Text = 'Moc 1.5';
            app.Moc2Menu = uimenu(app.WinietaMenu);
            app.Moc2Menu.MenuSelectedFcn = createCallbackFcn(app, @Moc2MenuSelected, true);
            app.Moc2Menu.Text = 'Moc 2';
            app.EfektyartystyczneMenu = uimenu(app.ProjektUIFigure);
            app.EfektyartystyczneMenu.Text = 'Efekty artystyczne';
            app.SzkicowkiemMenu = uimenu(app.EfektyartystyczneMenu);
            app.SzkicowkiemMenu.Text = 'Szkic ołówkiem';
            app.SzkicprostyMenu = uimenu(app.SzkicowkiemMenu);
            app.SzkicprostyMenu.MenuSelectedFcn = createCallbackFcn(app, @SzkicprostyMenuSelected, true);
            app.SzkicprostyMenu.Text = 'Szkic prosty';
            app.Szkiczmaskx1Menu = uimenu(app.SzkicowkiemMenu);
            app.Szkiczmaskx1Menu.MenuSelectedFcn = createCallbackFcn(app, @Szkiczmaskx1MenuSelected, true);
            app.Szkiczmaskx1Menu.Text = 'Szkic z maską x1';
            app.Szkiczmaskx2Menu = uimenu(app.SzkicowkiemMenu);
            app.Szkiczmaskx2Menu.MenuSelectedFcn = createCallbackFcn(app, @Szkiczmaskx2MenuSelected, true);
            app.Szkiczmaskx2Menu.Text = 'Szkic z maską x2';
            app.MalarstwoolejneMenu = uimenu(app.EfektyartystyczneMenu);
            app.MalarstwoolejneMenu.Text = 'Malarstwo olejne';
            app.Rozmiarbloku4Menu = uimenu(app.MalarstwoolejneMenu);
            app.Rozmiarbloku4Menu.MenuSelectedFcn = createCallbackFcn(app, @Rozmiarbloku4MenuSelected, true);
            app.Rozmiarbloku4Menu.Text = 'Rozmiar bloku 4';
            app.Rozmiarbloku8Menu = uimenu(app.MalarstwoolejneMenu);
            app.Rozmiarbloku8Menu.MenuSelectedFcn = createCallbackFcn(app, @Rozmiarbloku8MenuSelected, true);
            app.Rozmiarbloku8Menu.Text = 'Rozmiar bloku 8';
            app.Rozmiarbloku16Menu = uimenu(app.MalarstwoolejneMenu);
            app.Rozmiarbloku16Menu.MenuSelectedFcn = createCallbackFcn(app, @Rozmiarbloku16MenuSelected, true);
            app.Rozmiarbloku16Menu.Text = 'Rozmiar bloku 16';
            app.Rozmiarbloku32Menu = uimenu(app.MalarstwoolejneMenu);
            app.Rozmiarbloku32Menu.MenuSelectedFcn = createCallbackFcn(app, @Rozmiarbloku32MenuSelected, true);
            app.Rozmiarbloku32Menu.Text = 'Rozmiar bloku 32';
            app.AkwareleMenu = uimenu(app.EfektyartystyczneMenu);
            app.AkwareleMenu.MenuSelectedFcn = createCallbackFcn(app, @AkwareleMenuSelected, true);
            app.AkwareleMenu.Text = 'Akwarele';
            app.KoloroweszkoMenu = uimenu(app.EfektyartystyczneMenu);
            app.KoloroweszkoMenu.Text = 'Kolorowe szkło';
            app.Rozmiarkafelka4Menu = uimenu(app.KoloroweszkoMenu);
            app.Rozmiarkafelka4Menu.MenuSelectedFcn = createCallbackFcn(app, @Rozmiarkafelka4MenuSelected, true);
            app.Rozmiarkafelka4Menu.Text = 'Rozmiar kafelka 4';
            app.Rozmiarkafelka6Menu = uimenu(app.KoloroweszkoMenu);
            app.Rozmiarkafelka6Menu.MenuSelectedFcn = createCallbackFcn(app, @Rozmiarkafelka6MenuSelected, true);
            app.Rozmiarkafelka6Menu.Text = 'Rozmiar kafelka 6';
            app.Rozmiarkafelka12Menu = uimenu(app.KoloroweszkoMenu);
            app.Rozmiarkafelka12Menu.MenuSelectedFcn = createCallbackFcn(app, @Rozmiarkafelka12MenuSelected, true);
            app.Rozmiarkafelka12Menu.Text = 'Rozmiar kafelka 12';
            app.ChromatycznerozmycieMenu = uimenu(app.EfektyartystyczneMenu);
            app.ChromatycznerozmycieMenu.MenuSelectedFcn = createCallbackFcn(app, @ChromatycznerozmycieMenuSelected, true);
            app.ChromatycznerozmycieMenu.Text = 'Chromatyczne rozmycie';
            app.Przesuniecieo5Menu = uimenu(app.ChromatycznerozmycieMenu);
            app.Przesuniecieo5Menu.MenuSelectedFcn = createCallbackFcn(app, @Przesuniecieo5MenuSelected, true);
            app.Przesuniecieo5Menu.Text = 'Przesuniecie o 5';
            app.Przesunicioo15Menu = uimenu(app.ChromatycznerozmycieMenu);
            app.Przesunicioo15Menu.MenuSelectedFcn = createCallbackFcn(app, @Przesunicioo15MenuSelected, true);
            app.Przesunicioo15Menu.Text = 'Przesunięcio o 15';
            app.NeonMenu = uimenu(app.EfektyartystyczneMenu);
            app.NeonMenu.MenuSelectedFcn = createCallbackFcn(app, @NeonMenuSelected, true);
            app.NeonMenu.Text = 'Neon';
            app.PlakatMenu = uimenu(app.EfektyartystyczneMenu);
            app.PlakatMenu.Text = 'Plakat';
            app.Iloodcienii4Menu = uimenu(app.PlakatMenu);
            app.Iloodcienii4Menu.MenuSelectedFcn = createCallbackFcn(app, @Iloodcienii4MenuSelected, true);
            app.Iloodcienii4Menu.Text = 'Ilość odcienii 4';
            app.Iloodcienii8Menu = uimenu(app.PlakatMenu);
            app.Iloodcienii8Menu.MenuSelectedFcn = createCallbackFcn(app, @Iloodcienii8MenuSelected, true);
            app.Iloodcienii8Menu.Text = 'Ilość odcienii 8';
            app.Iloodcienii10Menu = uimenu(app.PlakatMenu);
            app.Iloodcienii10Menu.MenuSelectedFcn = createCallbackFcn(app, @Iloodcienii10MenuSelected, true);
            app.Iloodcienii10Menu.Text = 'Ilość odcienii 10';
            app.FaleMenu = uimenu(app.EfektyartystyczneMenu);
            app.FaleMenu.Text = 'Fale';
            app.SabeMenu = uimenu(app.FaleMenu);
            app.SabeMenu.MenuSelectedFcn = createCallbackFcn(app, @SabeMenuSelected, true);
            app.SabeMenu.Text = 'Słabe';
            app.rednieMenu = uimenu(app.FaleMenu);
            app.rednieMenu.MenuSelectedFcn = createCallbackFcn(app, @rednieMenuSelected, true);
            app.rednieMenu.Text = 'Średnie';
            app.MocneMenu = uimenu(app.FaleMenu);
            app.MocneMenu.MenuSelectedFcn = createCallbackFcn(app, @MocneMenuSelected, true);
            app.MocneMenu.Text = 'Mocne';
            app.EfektogniaMenu = uimenu(app.EfektyartystyczneMenu);
            app.EfektogniaMenu.MenuSelectedFcn = createCallbackFcn(app, @EfektogniaMenuSelected, true);
            app.EfektogniaMenu.Text = 'Efekt ognia';
            app.StarezdjcieMenu = uimenu(app.EfektyartystyczneMenu);
            app.StarezdjcieMenu.MenuSelectedFcn = createCallbackFcn(app, @StarezdjcieMenuSelected, true);
            app.StarezdjcieMenu.Text = 'Stare zdjęcie';
            app.UIAxes = uiaxes(app.ProjektUIFigure);
            title(app.UIAxes, 'Obraz orginalny')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.ProjektUIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.TabGroup = uitabgroup(app.ProjektUIFigure);
            app.InformacjeTab = uitab(app.TabGroup);
            app.InformacjeTab.Title = 'Informacje';
            app.UIAxes3 = uiaxes(app.InformacjeTab);
            xlabel(app.UIAxes3, 'Wartości pikseli')
            ylabel(app.UIAxes3, 'Częstość występowania')
            zlabel(app.UIAxes3, 'Z')
            app.NazwaLabel = uilabel(app.InformacjeTab);
            app.NazwaLabel.Text = 'Nazwa: ';
            app.ciekafolderuLabel = uilabel(app.InformacjeTab);
            app.ciekafolderuLabel.Text = 'Ścieżka folderu: ';
            app.brakLabel = uilabel(app.InformacjeTab);
            app.brakLabel.Text = 'brak';
            app.brakLabel_2 = uilabel(app.InformacjeTab);
            app.brakLabel_2.Text = 'brak';
            app.InformacjeowczytanymobrazieLabel = uilabel(app.InformacjeTab);
            app.InformacjeowczytanymobrazieLabel.Text = 'Informacje o wczytanym obrazie';
            app.RozmiarplikuLabel = uilabel(app.InformacjeTab);
            app.RozmiarplikuLabel.Text = 'Rozmiar pliku:';
            app.brakLabel_3 = uilabel(app.InformacjeTab);
            app.brakLabel_3.Text = 'brak';
            app.WysokoLabel = uilabel(app.InformacjeTab);
            app.WysokoLabel.Text = 'Wysokość:';
            app.SzerokoLabel = uilabel(app.InformacjeTab);
            app.SzerokoLabel.Text = 'Szerokość: ';
            app.brakLabel_4 = uilabel(app.InformacjeTab);
            app.brakLabel_4.Text = 'brak';
            app.brakLabel_5 = uilabel(app.InformacjeTab);
            app.brakLabel_5.Text = 'brak';
            app.FormatLabel = uilabel(app.InformacjeTab);
            app.FormatLabel.Text = 'Format:';
            app.brakLabel_6 = uilabel(app.InformacjeTab);
            app.brakLabel_6.Text = 'brak';
            app.GbiawbitachLabel = uilabel(app.InformacjeTab);
            app.GbiawbitachLabel.Text = 'Głębia w bitach: ';
            app.brakLabel_7 = uilabel(app.InformacjeTab);
            app.brakLabel_7.Text = 'brak';
            app.OdwzorowaniekoloruLabel = uilabel(app.InformacjeTab);
            app.OdwzorowaniekoloruLabel.Text = 'Odwzorowanie koloru: ';
            app.brakLabel_8 = uilabel(app.InformacjeTab);
            app.brakLabel_8.Text = 'brak';
            app.PlikLabel = uilabel(app.InformacjeTab);
            app.PlikLabel.Text = 'Plik';
            app.ObrazLabel = uilabel(app.InformacjeTab);
            app.ObrazLabel.Text = 'Obraz';
            app.DatamodyfikacjiLabel = uilabel(app.InformacjeTab);
            app.DatamodyfikacjiLabel.Text = 'Data modyfikacji:';
            app.brakLabel_9 = uilabel(app.InformacjeTab);
            app.brakLabel_9.Text = 'brak';
            app.HistogramRGBLabel = uilabel(app.InformacjeTab);
            app.HistogramRGBLabel.Text = 'Histogram RGB';
            app.GenerujhistogramButton = uibutton(app.InformacjeTab, 'push');
            app.GenerujhistogramButton.ButtonPushedFcn = createCallbackFcn(app, @GenerujhistogramButtonPushed, true);
            app.GenerujhistogramButton.Text = 'Generuj histogram';
            app.PoprawajakociTab = uitab(app.TabGroup);
            app.PoprawajakociTab.Title = 'Poprawa jakości';
            app.WartoMaxSliderLabel = uilabel(app.PoprawajakociTab);
            app.WartoMaxSliderLabel.Text = 'Wartość Max';
            app.WartoMaxSlider = uislider(app.PoprawajakociTab);
            app.WartoMaxSlider.Limits = [0.5 1];
            app.WartoMaxSlider.ValueChangingFcn = createCallbackFcn(app, @WartoMaxSliderValueChanging, true);
            app.WartoMaxSlider.Value = 1;
            app.WartoMinSliderLabel = uilabel(app.PoprawajakociTab);
            app.WartoMinSliderLabel.Text = 'Wartość Min';
            app.WartoMinSlider = uislider(app.PoprawajakociTab);
            app.WartoMinSlider.Limits = [0 0.5];
            app.WartoMinSlider.ValueChangingFcn = createCallbackFcn(app, @WartoMinSliderValueChanging, true);
            app.JasnoikontrastLabel = uilabel(app.PoprawajakociTab);
            app.JasnoikontrastLabel.Text = 'Jasność i kontrast';
            app.ZapiszButton = uibutton(app.PoprawajakociTab, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Text = 'Zapisz';
            app.RestartButton = uibutton(app.PoprawajakociTab, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.WartoSliderLabel = uilabel(app.PoprawajakociTab);
            app.WartoSliderLabel.Text = 'Wartość';
            app.WartoSlider = uislider(app.PoprawajakociTab);
            app.WartoSlider.Limits = [0 2];
            app.WartoSlider.ValueChangedFcn = createCallbackFcn(app, @WartoSliderValueChanged, true);
            app.WartoSlider.ValueChangingFcn = createCallbackFcn(app, @WartoSliderValueChanging, true);
            app.ZapiszButton_2 = uibutton(app.PoprawajakociTab, 'push');
            app.ZapiszButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButton_2Pushed, true);
            app.ZapiszButton_2.Text = 'Zapisz';
            app.WyostrzenieLabel = uilabel(app.PoprawajakociTab);
            app.WyostrzenieLabel.Text = 'Wyostrzenie';
            app.WartoSlider_2Label = uilabel(app.PoprawajakociTab);
            app.WartoSlider_2Label.Text = 'Wartość';
            app.WartoSlider_2 = uislider(app.PoprawajakociTab);
            app.WartoSlider_2.Limits = [0 3];
            app.WartoSlider_2.ValueChangingFcn = createCallbackFcn(app, @WartoSlider_2ValueChanging, true);
            app.UsuwanieszumwLabel = uilabel(app.PoprawajakociTab);
            app.UsuwanieszumwLabel.Text = 'Usuwanie szumów';
            app.ZapiszButton_3 = uibutton(app.PoprawajakociTab, 'push');
            app.ZapiszButton_3.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButton_3Pushed, true);
            app.ZapiszButton_3.Text = 'Zapisz';
            app.KorektakolorwTab = uitab(app.TabGroup);
            app.KorektakolorwTab.Title = 'Korekta kolorów';
            app.HueSliderLabel = uilabel(app.KorektakolorwTab);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.KorektakolorwTab);
            app.HueSlider.Limits = [0 0.5];
            app.HueSlider.ValueChangingFcn = createCallbackFcn(app, @HueSliderValueChanging, true);
            app.ValueSliderLabel = uilabel(app.KorektakolorwTab);
            app.ValueSliderLabel.Text = 'Value';
            app.ValueSlider = uislider(app.KorektakolorwTab);
            app.ValueSlider.Limits = [0 2];
            app.ValueSlider.ValueChangingFcn = createCallbackFcn(app, @ValueSliderValueChanging, true);
            app.ValueSlider.Value = 1;
            app.SaturationSlider_2Label = uilabel(app.KorektakolorwTab);
            app.SaturationSlider_2Label.Text = 'Saturation';
            app.SaturationSlider_2 = uislider(app.KorektakolorwTab);
            app.SaturationSlider_2.Limits = [0 5];
            app.SaturationSlider_2.ValueChangingFcn = createCallbackFcn(app, @SaturationSlider_2ValueChanging, true);
            app.SaturationSlider_2.Value = 1;
            app.KorektabarwHSVLabel = uilabel(app.KorektakolorwTab);
            app.KorektabarwHSVLabel.Text = 'Korekta barw HSV';
            app.ZapiszzmianyButton = uibutton(app.KorektakolorwTab, 'push');
            app.ZapiszzmianyButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszzmianyButtonPushed, true);
            app.ZapiszzmianyButton.Text = 'Zapisz zmiany';
            app.RestartButton_2 = uibutton(app.KorektakolorwTab, 'push');
            app.RestartButton_2.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton_2.Text = 'Restart';
            app.ZapiszefektyButton = uibutton(app.ProjektUIFigure, 'push');
            app.ZapiszefektyButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszefektyButtonPushed, true);
            app.ZapiszefektyButton.Enable = 'off';
            app.ZapiszefektyButton.Tooltip = {'Spowoduje zapisanie obrazu na który aktualnie nałożony jest wybrany efekt.'};
            app.ZapiszefektyButton.Text = 'Zapisz efekty';
            app.ResetButton = uibutton(app.ProjektUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Tooltip = {'Spowoduje zrestartowanie obrazu do wczytanego na samym początku.'};
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.ProjektUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projekt

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProjektUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProjektUIFigure)
        end
    end
end